classdef BT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CLASSROOMEXO_Figure             matlab.ui.Figure
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        EmergencyManager                matlab.ui.container.Panel
        EmergnecySTOPButton             matlab.ui.control.StateButton
        ConnectionManager               matlab.ui.container.Panel
        RefreshButton_Connection_List   matlab.ui.control.Button
        BTDeviceDropDown                matlab.ui.control.DropDown
        BTDeviceDropDownLabel           matlab.ui.control.Label
        BTStatusImage                   matlab.ui.control.Image
        ConnectButton                   matlab.ui.control.Button
        StatusManager                   matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        BatteryManager                  matlab.ui.container.Panel
        BatteryPercentage               matlab.ui.control.Label
        BatteryImage                    matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        PIControllerTab                 matlab.ui.container.Tab
        Transfer                        matlab.ui.container.Panel
        ServoControlPanel               matlab.ui.container.Panel
        ServoSpeedKnob                  matlab.ui.control.DiscreteKnob
        SpeedKnobLabel                  matlab.ui.control.Label
        ServoPowerSwitch                matlab.ui.control.Switch
        ExecuteButton                   matlab.ui.control.Button
        Servo_Plot_Reset                matlab.ui.control.Button
        ServoPowerSwitchLabel           matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        TransferFunctionTab             matlab.ui.container.Tab
        RefreshButton_3                 matlab.ui.control.Button
        ShowInputSignalCheckBox         matlab.ui.control.CheckBox
        OptimizePIDButton               matlab.ui.control.Button
        uLabel                          matlab.ui.control.Label
        vLabel                          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        y_mLabel                        matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        rLabel                          matlab.ui.control.Label
        Image9_8                        matlab.ui.control.Image
        Image9_11                       matlab.ui.control.Image
        Image9_10                       matlab.ui.control.Image
        Image9_9                        matlab.ui.control.Image
        Image9_7                        matlab.ui.control.Image
        Image9_6                        matlab.ui.control.Image
        Image9_5                        matlab.ui.control.Image
        Image9_4                        matlab.ui.control.Image
        PIDControllerPanel              matlab.ui.container.Panel
        KdEditFieldPIDController        matlab.ui.control.NumericEditField
        K_iLabel_4                      matlab.ui.control.Label
        KiEditFieldPIDController        matlab.ui.control.NumericEditField
        K_iLabel_3                      matlab.ui.control.Label
        KpEditFieldPIDController        matlab.ui.control.NumericEditField
        K_iEditField_2Label_4           matlab.ui.control.Label
        PControllerPanel                matlab.ui.container.Panel
        KpEditFieldPController          matlab.ui.control.NumericEditField
        K_iEditField_2Label_2           matlab.ui.control.Label
        PIControllerPanel               matlab.ui.container.Panel
        KiEditFieldPIController         matlab.ui.control.NumericEditField
        K_iLabel_2                      matlab.ui.control.Label
        KpEditFieldPIController         matlab.ui.control.NumericEditField
        K_iEditField_2Label_3           matlab.ui.control.Label
        HighRefDegreeEditField          matlab.ui.control.NumericEditField
        LowRefDegreeEditField           matlab.ui.control.NumericEditField
        TuningPIPanel_Control_Params    matlab.ui.container.Panel
        TiEditFieldPIControl            matlab.ui.control.NumericEditField
        T_iEditFieldLabel               matlab.ui.control.Label
        KiEditFieldPIControl            matlab.ui.control.NumericEditField
        K_iEditField_2Label             matlab.ui.control.Label
        Gs_PlantPanel                   matlab.ui.container.Panel
        omega_G150Label                 matlab.ui.control.Label
        DivisionLine                    matlab.ui.control.Label
        GsLabel                         matlab.ui.control.Label
        numEditField                    matlab.ui.control.EditField
        denEditField                    matlab.ui.control.EditField
        Image9                          matlab.ui.control.Image
        Image6_4                        matlab.ui.control.Image
        Image7_2                        matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        InputResponseButtonGroup        matlab.ui.container.ButtonGroup
        NaturalButton                   matlab.ui.control.RadioButton
        RampButton                      matlab.ui.control.RadioButton
        StepButton                      matlab.ui.control.RadioButton
        TuningPIDPanel_Control_Params   matlab.ui.container.Panel
        zetaEditField_3Label_5          matlab.ui.control.Label
        ZetaEditFieldPIDControl         matlab.ui.control.NumericEditField
        BetaEditFieldPIDControl         matlab.ui.control.NumericEditField
        cLabel_11                       matlab.ui.control.Label
        KiEditFieldPIDControl           matlab.ui.control.NumericEditField
        K_iLabel                        matlab.ui.control.Label
        TauEditFieldPIDControl          matlab.ui.control.NumericEditField
        KpLabel_5                       matlab.ui.control.Label
        TuningPIPanel_Design_Params     matlab.ui.container.Panel
        PhiMEditFieldPIDesign           matlab.ui.control.NumericEditField
        cLabel_10                       matlab.ui.control.Label
        OmegaCEditFieldPIDesign         matlab.ui.control.NumericEditField
        cLabel_9                        matlab.ui.control.Label
        TuningPIDPanel_Design_Params    matlab.ui.container.Panel
        zetaEditField_3Label_4          matlab.ui.control.Label
        ZetaEditFieldPIDDesign          matlab.ui.control.NumericEditField
        OmegaCEditFieldPIDDesign        matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        BetaEditFieldPIDDesign          matlab.ui.control.NumericEditField
        cLabel_8                        matlab.ui.control.Label
        PhiMEditFieldPIDDesign          matlab.ui.control.NumericEditField
        KpLabel_4                       matlab.ui.control.Label
        OptimizePIButton                matlab.ui.control.Button
        TrackChangesCheckBox            matlab.ui.control.CheckBox
        ServoAngleLimitsSlider          matlab.ui.control.RangeSlider
        RefreshButton                   matlab.ui.control.Button
        PLOTButton                      matlab.ui.control.Button
        ServoAngleLimitsSliderLabel     matlab.ui.control.Label
        ControlDropDown                 matlab.ui.control.DropDown
        Image4                          matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_4                        matlab.ui.control.Image
        Image7_3                        matlab.ui.control.Image
        InputSignalTab                  matlab.ui.container.Tab
        NaturalPeriodSpinner            matlab.ui.control.Spinner
        PeriodSpinner_2Label_2          matlab.ui.control.Label
        NaturalRepetitionsSpinner       matlab.ui.control.Spinner
        RepetitionsSpinner_2Label_2     matlab.ui.control.Label
        RampPeriodSpinner               matlab.ui.control.Spinner
        PeriodSpinner_2Label            matlab.ui.control.Label
        RampRepetitionsSpinner          matlab.ui.control.Spinner
        RepetitionsSpinner_2Label       matlab.ui.control.Label
        StepPeriodSpinner               matlab.ui.control.Spinner
        PeriodSpinnerLabel              matlab.ui.control.Label
        StepRepetitionsSpinner          matlab.ui.control.Spinner
        RepetitionsSpinnerLabel         matlab.ui.control.Label
        NaturalMovementSignalParametersLabel  matlab.ui.control.Label
        RampSignalParametersLabel       matlab.ui.control.Label
        StepSignalParametersLabel       matlab.ui.control.Label
        InputSignalGraph                matlab.ui.control.UIAxes
        ServoTrajectory                 matlab.ui.control.UIAxes
        FunctionsResonseTab             matlab.ui.container.Panel
        StepResponsePanel               matlab.ui.container.Panel
        ZerosLabel                      matlab.ui.control.Label
        PolesLabel                      matlab.ui.control.Label
        TimeConstantLabel               matlab.ui.control.Label
        PeakLabel                       matlab.ui.control.Label
        OverShootLabel                  matlab.ui.control.Label
        SettlingTimeLabel               matlab.ui.control.Label
        TransientTimeLabel              matlab.ui.control.Label
        StepReference                   matlab.ui.control.UIAxes
        StepDisturbance                 matlab.ui.control.UIAxes
        EMGControlTab                   matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ThresholdControlTab             matlab.ui.container.Tab
        EMGControlParameters_2          matlab.ui.container.Panel
        EMGModeSwitch                   matlab.ui.control.Switch
        CH2DropDown                     matlab.ui.control.DropDown
        EMGLowerThreshold               matlab.ui.control.NumericEditField
        EMGUpperThreshold               matlab.ui.control.NumericEditField
        SpeedSetting_EMG                matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel            matlab.ui.control.Label
        ApplyThresholdsButton_EMG       matlab.ui.control.StateButton
        EMGLowerThresholdSlider         matlab.ui.control.Slider
        LowerLabel                      matlab.ui.control.Label
        EMGUpperThresholdSlider         matlab.ui.control.Slider
        UpperLabel                      matlab.ui.control.Label
        EMGShowThresholdCheckBox        matlab.ui.control.CheckBox
        ModeSwitch_2                    matlab.ui.control.RockerSwitch
        ModeSwitch_2Label               matlab.ui.control.Label
        AppliedFilterButtonGroup_EMG    matlab.ui.container.ButtonGroup
        EMG_EWMVA                       matlab.ui.control.RadioButton
        EMG_CenteredMovingAverageButton  matlab.ui.control.RadioButton
        EMG_LeftSidedMovingAverageButton  matlab.ui.control.RadioButton
        EMGChannelLabel_2               matlab.ui.control.Label
        ThresholdVLabel                 matlab.ui.control.Label
        StartRecordingSwitch_DualThresh_EMG  matlab.ui.control.RockerSwitch
        StartSwitchLabel_4              matlab.ui.control.Label
        CH1DropDown                     matlab.ui.control.DropDown
        ElectromyographyControlGraphs_2  matlab.ui.container.Panel
        IMUPlot_EMG                     matlab.ui.control.UIAxes
        EMG_OneCH                       matlab.ui.control.UIAxes
        ForceControlTab                 matlab.ui.container.Tab
        TabGroup3_2                     matlab.ui.container.TabGroup
        ThresholdTab                    matlab.ui.container.Tab
        ForceControlGraphs              matlab.ui.container.Panel
        ForceParameters                 matlab.ui.container.Panel
        proportionalgainSlider          matlab.ui.control.Slider
        proportionalgainSliderLabel     matlab.ui.control.Label
        ControlModeDropDown             matlab.ui.control.DropDown
        ControlModeDropDownLabel        matlab.ui.control.Label
        ThresholdmNLabel                matlab.ui.control.Label
        ForceShowThresholdCheckBox      matlab.ui.control.CheckBox
        SpeedSetting_Force              matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel_4          matlab.ui.control.Label
        ApplyThresholdsButton_Force     matlab.ui.control.StateButton
        AppliedFilterButtonGroup_Force  matlab.ui.container.ButtonGroup
        EWMAButton                      matlab.ui.control.RadioButton
        CenteredMovingAverageButton     matlab.ui.control.RadioButton
        LeftsidedMovingAverageButton    matlab.ui.control.RadioButton
        StartRecordingSwitch_DualThresh_Force  matlab.ui.control.RockerSwitch
        StartSwitchLabel_7              matlab.ui.control.Label
        ForceLowerThresholdSlider       matlab.ui.control.Slider
        LowerThresholdSliderLabel       matlab.ui.control.Label
        ForceUpperThresholdSlider       matlab.ui.control.Slider
        UpperThresholdSliderLabel       matlab.ui.control.Label
        IMUPlot_Force                   matlab.ui.control.UIAxes
        ForceReading                    matlab.ui.control.UIAxes
        ArmWeightCompensationTab        matlab.ui.container.Tab
        ForceControlGraphs_2            matlab.ui.container.Panel
        ForceParameters_2               matlab.ui.container.Panel
        ServoPowerSwitch_2              matlab.ui.control.Switch
        ServoPowerSwitch_2Label         matlab.ui.control.Label
        UserSpecificParametersLabel     matlab.ui.control.Label
        UserWeightkgSpinner             matlab.ui.control.Spinner
        UserWeightkgSpinnerLabel        matlab.ui.control.Label
        UserHeightmSpinner              matlab.ui.control.Spinner
        UserHeightmSpinnerLabel         matlab.ui.control.Label
        SpeedSetting_Force_2            matlab.ui.control.DiscreteKnob
        ServoMotorSpeedLabel_5          matlab.ui.control.Label
        AppliedFilterButtonGroup_Force_2  matlab.ui.container.ButtonGroup
        EWMVAButton_2                   matlab.ui.control.RadioButton
        CenteredMovingAverageButton_2   matlab.ui.control.RadioButton
        LeftSidedMovingAverageButton_2  matlab.ui.control.RadioButton
        StartRecordingSwitch_Force_2    matlab.ui.control.RockerSwitch
        StartSwitchLabel_8              matlab.ui.control.Label
        IMUPlot_Force_2                 matlab.ui.control.UIAxes
        ForceReading_2                  matlab.ui.control.UIAxes
        IMUPosePlotTab                  matlab.ui.container.Tab
        IMU_Parameters                  matlab.ui.container.Panel
        StartRecordingSwitch_IMU_2      matlab.ui.control.RockerSwitch
        StartSwitchLabel_9              matlab.ui.control.Label
        StartRecordingSwitch_IMU        matlab.ui.control.RockerSwitch
        StartSwitchLabel_5              matlab.ui.control.Label
        IMUPosePlot                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        IMUPlot                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        % initialize datalines for live plotting
        Offset_Force_filt
        zero_force_offset
        PLOT_degree;
        % Flags and variables that are accesible for all functions
        connectFlag = false;
        pairedFlag = false;
        selectFlag = false;
        chosen_option;
        chosen_option_indx;
        
        pair_status = "";
        avail_status = "";
        connected_device;
        con_options_friendlyName = "";
        con_options_COMPorts = "";
        charge_status = 0;
        servoState = false;
        Servo_Speed = 1;
        PI_design_flag = false;
        PI_control_flag = true;
        PID_design_flag = false;
        PID_control_flag = false;
        P_controller_flag = false;
        PI_controller_flag = false;
        PID_controller_flag = false;
        Compare_changes_flag = true;
        ShowInputSignal_flag = true;
        upper_threshold;
        lower_threshold;
        upperHandle = [];
        lowerHandle = [];
        EMG_Upper_Threshold;
        EMG_Lower_Threshold;
        angleStep = 100;
        BiChannel_flag = false;
        analogEMG0Pin = 1;
        analogEMG1Pin = 2;
        ForceControlMode = 'standard';

        Stable_Factor;
        Danger;

        angle_feedback;
        angle_END;
        pathtest = "";
        batteryFlag, battery_voltage, battery_percentage;


        message2receive;
        message2send;
        checksum;
        opcode;

        MaxPoints = 50;  % Maximum number of points to display

        %% PI controller params
        Ti = 0;
        Kf = 0;
        Tf = 0;
        Td = 0;
        tau = 0;
        wc = 0;
        phim = 0;
        beta = 0;
        zeta = 0;
        Ki = 0;
        Kp = 0;
        Kd = 0;
        signalType = uint8(1);

        %% Filter params
        filter_type
        window_size
        
         % RGB Colors for LED
        color_green = 0x03;
        color_white = 0x01;
        color_orange = 0x02;
        color_blue = 0x04;
        color_yellow = 0x06;
        color_off = 0x00;
        color_more_green = 0x07
        color_red = 0x05;
        
        %% Input Signals params
        repetitions
        period

        %% IMU 
        PlotManager
        imuPlotManager
        imuPlotManagerForce
        imuPlotManagerEMG
        IMUPlotObject
        % IMUPlot
        
        UIStateManager
        ControlFunctions
        ServoPlotManager
        ControllerParams
        %% EMG Data Simulation
        MVCValues = [NaN];        % Array to store measured MVC values
        AcquisitionTime = 15;  % Total acquisition time in seconds
        MiddleDuration = 5;    % Duration for the middle seconds
        SamplingRate = 66;   % sEMG sampling rate in Hz
        sEMGData  =[];

        ForceValues = [NaN];
        AngleValues = [NaN];
        CommManager
        CommFunctions
    end
    
    properties (Access = private)
        
    end

    methods (Access = private)
        function initializeCommunication(app, COMPort)
            % Create Communication Manager objects
            app.CommManager = CommunicationManager(COMPort);
            app.CommFunctions = CommunicationFunctions(app.CommManager);

            % Create Plot Manager objects
            app.imuPlotManager = SensorPlotManager(app.CommFunctions);
            app.imuPlotManagerForce = SensorPlotManager(app.CommFunctions);
            app.imuPlotManagerEMG = SensorPlotManager(app.CommFunctions);
            app.ControllerParams = ControllerParameters();
            app.ServoPlotManager = ServoPlotManager(app);

            % Initialize plots
            app.imuPlotManager.initializePlot('imu', app.UIAxes, app.IMUPlot);
            app.imuPlotManagerForce.initializePlot('force', app.ForceReading, app.IMUPlot_Force);
            app.imuPlotManagerEMG.initializePlot('emg', app.EMG_OneCH, app.IMUPlot_EMG);
            
        end

        % not done yeat...
        function cbool = check_checksum(app)
                % checksum = mod(sum([msg2receive.header, msg2receive.opcode, typecast(uint16(msg2receive.dataLength),'uint8'), msg2receive.data]), 256);
                calc_checksum = mod(sum([app.msg2receive.header, app.msg2receive.opcode, typecast(uint16(app.msg2receive.dataLength),'uint8')]), 256);
                if calc_checksum == csum
                    cbool = true;
                else
                    cbool = false;
                end
            end
    
    %------------------------------
    %% set EMG Threshold in GUI
        function AdjustEMGThresholdsGUI(app)
            EMG_thresh_upper = app.EMGUpperThresholdSlider.Value;
            EMG_thresh_lower = app.EMGLowerThresholdSlider.Value;
            
            
            if EMG_thresh_lower >= EMG_thresh_upper && (EMG_thresh_upper >=1)
                EMG_thresh_lower = EMG_thresh_upper-1;
            elseif (EMG_thresh_lower >= EMG_thresh_upper) && (EMG_thresh_upper == 0)
                EMG_thresh_lower = 0;
                EMG_thresh_upper = 1;
            end
            if EMG_thresh_lower < EMG_thresh_upper
                app.EMGUpperThresholdSlider.Value = EMG_thresh_upper;
                app.EMGLowerThresholdSlider.Value = EMG_thresh_lower;
                app.EMGUpperThreshold.Value = EMG_thresh_upper;
                app.EMGLowerThreshold.Value = EMG_thresh_lower;
            end
        end

    % set Force Threshold in GUI


    % set Servo Thresholds in GUI
    function AdjustServoLimitsGUI(app)
        value_low = app.LowRefDegreeEditField.Value;
        value_high = app.HighRefDegreeEditField.Value;
        
        if value_low >= value_high && (value_high >=1)
            value_low = value_high-1;
        elseif (value_low >= value_high) && (value_high == 0)
            value_low = 0;
            value_high = 1;
        end
        if value_low < value_high
            app.ServoAngleLimitsSlider.Value(1) = value_low; 
            app.ServoAngleLimitsSlider.Value(2) = value_high;
            app.LowRefDegreeEditField.Value = value_low;
            app.HighRefDegreeEditField.Value = value_high;
        end
    end

    function AdjustServoLimitsSliderGUI(app)
        value_low = app.ServoAngleLimitsSlider.Value(1); 
        value_high = app.ServoAngleLimitsSlider.Value(2);
        
        if value_low >= value_high && (value_high >=1)
            value_low = value_high-1;
        elseif (value_low >= value_high) && (value_high == 0)
            value_low = 0;
            value_high = 1;
        end
        if value_low < value_high
            app.ServoAngleLimitsSlider.Value(1) = value_low; 
            app.ServoAngleLimitsSlider.Value(2) = value_high;
            app.LowRefDegreeEditField.Value = value_low;
            app.HighRefDegreeEditField.Value = value_high;
        end
    end


    %% FOR DEBUG ONLY
    % Random EMG Values
    %----------------------------------------------------------------
        function sEMGValue = acquireData(app)
                % Simulate sEMG data acquisition
                
                    % Replace this with your actual sEMG data acquisition code
                    sEMGValue = 2+rand()*5;  % Simulated sEMG value
                    app.sEMGData = [app.sEMGData, sEMGValue];                
                
        end
    
    %% Calculate MVC from Calibration
    %-----------------------------------------------------------------
        function calculateMVC(app)
                % Calculate MVC from the middle seconds of the acquired data
                middleStart = floor((app.AcquisitionTime - app.MiddleDuration) / 2) * app.SamplingRate + 1;
                middleEnd = middleStart + app.MiddleDuration * app.SamplingRate - 1;
    
                middleData = app.sEMGData(middleStart:middleEnd);
                maxMVC = max(middleData);
    
                % Store the measured MVC value
                app.MVCValues = [app.MVCValues, maxMVC];
        end
    
    %% Save data to CSV File
    %------------------------------------------------------------------
        function saveCSV(app, timestamp)
            % Save MVC, force, and angle values to a CSV file
            current_path = pwd;
            % Construct the dynamic filename with the current date
            temp_filename = 'CalibrationData' + timestamp + '.csv';
            filename = fullfile(current_path, temp_filename);
            fileheader = {'Timestamp', 'MVC Values', 'Force Values', 'Angles'};
                  
            %writetable(measurementTable, filename, 'WriteMode','replacefile');
            % Open the file for writing
            fileID = fopen(filename, 'w');
        
            % Write the header
            fprintf(fileID, '%s,%s,%s,%s\n', fileheader{:});
        
            % Write the data
            for i = 1:length(timestamp)
                fprintf(fileID, '%s,%.4f,%.4f,%.4f\n', timestamp(i), app.MVCValues(i), app.ForceValues(i), app.AngleValues(i));
            end
    
        
            % Close the file
            fclose(fileID);
        end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
            %global con_options;
            app.pathtest = fileparts(mfilename('fullpath'));
            
            app.BatteryImage.ImageSource = fullfile(app.pathtest, 'photos', 'fa-battery-0.png');
            app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-searching.png');
            app.StatusLamp.Color = [0,0,0]./255;
            app.Label.Text = 'not connected';

            report = ["Please be patient, we are searching for available devices..."; "This may take up to 10 seconds."];
            uialert(app.CLASSROOMEXO_Figure, report, "Alert", "Icon", "info");

            % Initialize UI state manager
            app.UIStateManager = UIStateManager(app);

            % Create an instance
            app.ControlFunctions = ControlFunctions();
            
            % Set initial states
            app.UIStateManager.changeConnectionTab('initial');
            app.UIStateManager.changeEMGControlTab('disable');
            app.UIStateManager.changeForceControlTab('disable');

            % search for paired Bluetooth devices in the Windows registry
            % and match them to the corresponding COM Ports
            pause(3);
            BT_Devices = findBT_devices_and_COMPorts();

            app.StartRecordingSwitch_IMU.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_IMUValueChanged, true);

            % In your GUI initialization
            % app.PlotManager = SensorPlotManager(app.CommFunctions);
            
            
            if (isempty(BT_Devices.COMport))
                app.BTDeviceDropDown.Items = "No devices";
                report = ["No Bluetooth device found."; "Check to enable Bluetooth connection on your device, make sure that the Classroom Edu-Exo-Pro device is powered on and was paired in the Windows Bluetooth Manager!."];
                uialert(app.CLASSROOMEXO_Figure,report, "Bluetooth Error", "Icon", "error");

                app.ConnectButton.Enable = "off";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = "k";

                pause(0.5);

                app.ConnectButton.Enable = "on";
                app.ConnectButton.Text = "Retry";
            else
                app.con_options_friendlyName = BT_Devices.friendlyName;
                app.con_options_COMPorts = BT_Devices.COMport;
                
                app.BTDeviceDropDown.Items = app.con_options_friendlyName;
                app.BTDeviceDropDown.Enable = 'on';
                app.BTDeviceDropDown.ItemsData=1:numel(app.BTDeviceDropDown.Items);
                app.ConnectButton.Enable = 'on';

                app.chosen_option = app.BTDeviceDropDown.Items{1, app.BTDeviceDropDown.Value};
                app.chosen_option_indx = app.BTDeviceDropDown.Value;
            end

            app.RefreshButton_Connection_List.Enable = "on";


        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.ConnectButton.Text == "Connect")
                app.ConnectButton.Enable = "off";
                    
                    if (~isempty(app.con_options_friendlyName))
                        app.pairedFlag = true;
                    else
                        app.pairedFlag = false;
                    end

                    if (app.connectFlag == false && app.pairedFlag == true)% && ((app.selectFlag  == true) | (indx == 1)))
                        % connect device
                        try
                            d = uiprogressdlg(app.CLASSROOMEXO_Figure,'Title','Please Wait','Message','Connecting to device');
    
                            COMport = app.con_options_COMPorts{app.chosen_option_indx};
                            
                            d.Value = .33; 
                            
                            app.initializeCommunication(COMport);
                        catch ME
                            report = getReport(ME);
                            uialert(app.CLASSROOMEXO_Figure,report,"Error","Icon","error");
                        end
                        d.Value = .5; 
                        connect_answer = app.CommFunctions.testConnection();
                        
        
                        if (connect_answer)
                            d.Value = .66; 
                            app.connectFlag = true;
                            app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-connected.png');
                            app.StatusLamp.Color = [0,0,255]./255;
                            app.Label.Text = 'connected';
                            app.UIStateManager.changeTransferFunctionTab('initial');
                            app.UIStateManager.changeEMGControlTab('initial');
                            app.UIStateManager.changeForceControlTab('initial');
                            app.UIStateManager.changeConnectionTab('connected');
                        else
                            app.connectFlag = false;
                        end
        
                        % app.pairedFlag = true;
                        % app.selectFlag = true;
                    elseif (app.connectFlag == false && app.selectFlag == false)
                        % please select device
                        report = ["No Bluetooth device selected."; "To connect with a Bluetooth device, please select one from the drop down list."];
                        uialert(app.CLASSROOMEXO_Figure, report, "Warning!","Icon", "warning");
                    elseif (app.connectFlag == false && app.pairedFlag == false)
                        % please pair device
                        report = ["Bluetooth device not paired."; "To use the Bluetooth device with this application, please pair it in your system settings."];
                        uialert(app.CLASSROOMEXO_Figure, report, "Warning!", "Icon", "warning");
                    elseif (app.connectFlag == true)
                        % device already connected
                        report = ["A Bluetooth device is already connected."; "If you want to disconnect, please restart the application and re-connect a new device!"];
                        uialert(app.CLASSROOMEXO_Figure, report, "Attention", "Icon", "info");
                    else
                        report = ["Unknown Error"; "Please Quit, and restart Application."];
                        uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error")
                    end
        
                    CheckBattery(app, 2);
                    
                    d.Value = .9; 
                    pause(0.5);

                    app.ConnectButton.Text = "Disconnect";
                    close(d);

            elseif(app.ConnectButton.Text == "Disconnect")
                 % Disconnect from Arduino
                connect_answer = app.CommFunctions.stopConnection();

                %disable UI Buttons
                app.UIStateManager.changeTransferFunctionTab('disable');
                app.ControlDropDown.Enable = "off";
                app.UIStateManager.changeServoControlPanel('disable');
                app.UIStateManager.changeForceControlTab('disable');
                app.UIStateManager.changeEMGControlTab('disable');
                app.UIStateManager.changeConnectionTab('initial');

                
                % reset flags
                app.pairedFlag = false;
                app.connectFlag = false;
                app.selectFlag = false;
                % app.connected_device = "";
                app.CommManager.cleanup();
                % delete(app.connected_device);
                clear global app.con_options;
                clear devicelist;
                pause(1);
                
                startupFcn(app);

            elseif(app.ConnectButton.Text == "Retry")
                app.BTDeviceDropDown.Enable = "off";
                app.BTDeviceDropDown.Placeholder = "select device";
                app.ConnectButton.Enable = 'off';
                app.ConnectButton.Text = "Connect";
                app.BTStatusImage.ImageSource = fullfile(app.pathtest, 'photos', 'mat-bluetooth-disabled.png');
                app.StatusLamp.Color = [0,0,0]./255; 
                % reset flags
                app.pairedFlag = false;
                app.connectFlag = false;
                app.selectFlag = false;
                % clear gobal app.connected_device ;
                clear global app.con_options;
                clear devicelist;
              
                pause(1);
                startupFcn(app);
            end


        end

        % Value changed function: BTDeviceDropDown
        function BTDeviceDropDownValueChanged(app, event)
            app.chosen_option = app.BTDeviceDropDown.Items{1, app.BTDeviceDropDown.Value};
            app.chosen_option_indx = app.BTDeviceDropDown.Value;
            
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            clc
            % reset lots if changes are not tracked
            if (~app.Compare_changes_flag)
                cla(app.StepDisturbance)
                cla(app.ServoTrajectory)
                cla(app.StepReference)
            end
            
            app.UIStateManager.changeTransferFunctionTab('disable');

            try
                app.Danger = 0;
                progressbar_controller = uiprogressdlg(app.CLASSROOMEXO_Figure, 'Title','Please Wait','Message','Initializing Controller...', 'Icon','info');
                %%%%%%%%  G(s) Plant Transfer Funtion %%%%%%%%%%
                % G(S) Num Values
                G_num = convertCharsToStrings(app.numEditField.Value);
                G_den = convertCharsToStrings(app.denEditField.Value);
                                
                G = app.ControlFunctions.analyze_transfer_function(G_num, G_den) % create the G(s) plant


                progressbar_controller.Message = 'Calculating Input Response';
                progressbar_controller.Value = .33;
%---------------%%%%%%%%  PID Controller Transfer Funtion %%%%%%%%%%--------------------------------------------
                if app.ControllerParams.PID_control_flag
                    % get values from GUI
                    app.ControllerParams.tau = app.TauEditFieldPIDControl.Value;
                    app.ControllerParams.Ki = app.KiEditFieldPIDControl.Value;
                    app.ControllerParams.beta = app.BetaEditFieldPIDControl.Value;
                    app.ControllerParams.zeta = app.ZetaEditFieldPIDControl.Value;

                    % calculate PID controller with Kp, Ki, and Kp parameters
                    [F_PID, app.ControllerParams.Kp,app.ControllerParams.Ki,app.ControllerParams.Kd] = app.ControlFunctions.pid_control(app.ControllerParams.tau, app.ControllerParams.Ki, app.ControllerParams.beta, app.ControllerParams.zeta);
                    
                    sys = feedback(G*F_PID,1); % System General Transfer Function with G(s) and F_PID(s)
                    sys_disturbance = feedback(sys,F_PID); % System Disturbance 
                    
                elseif app.ControllerParams.PID_design_flag
                    % get values from GUI
                    app.ControllerParams.wc = app.OmegaCEditFieldPIDDesign.Value;
                    app.ControllerParams.phim = app.PhiMEditFieldPIDDesign.Value;
                    app.ControllerParams.beta = app.BetaEditFieldPIDDesign.Value;
                    app.ControllerParams.zeta = app.ZetaEditFieldPIDDesign.Value;

                    % calculate PI controller with routine from Bengt
                    % Lennartson (see Eq. 8.37)
                    
                    [F_PID,app.ControllerParams.Ki,Kinf,tau,err] = app.ControlFunctions.pid_design(G,app.ControllerParams.wc,app.ControllerParams.phim,app.ControllerParams.beta,app.ControllerParams.zeta);  %% determines the correct pid controller for 
                    
                    sys = feedback(G*F_PID,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PID); % System Disturbance 

                    % --------------------------------------------------------------------
                    % Betrag von Fpid and der Stelle wc!! 
                    % ->>> FALSCHE BERECHNUNG (Gleichung 8.36)
                    % F_num = sqrt((1-((wc*tau)^2))^2 + (2*zeta*wc*tau)^2);
                    % F_den = wc*(sqrt(1+((wc*tau)/beta)^2));
                    % F_PID = ki*(F_num)/(F_den);

                    % sys = feedback(G*F_PID,1); % System General Transfer Function   FAAALSCH!!!!!"!!!!
                    % --------------------------------------------------------------------
                
%---------------%%%%%%%%  PI Controller Transfer Funtion %%%%%%%%%%--------------------------------------------
                elseif app.ControllerParams.PI_control_flag
                    % get values from GUI
                    app.ControllerParams.Ki = app.KiEditFieldPIControl.Value;
                    app.ControllerParams.Ti = app.TiEditFieldPIControl.Value;

                    [F_PI, app.ControllerParams.Kp, app.ControllerParams.Ki, app.ControllerParams.Kd] = app.ControlFunctions.pi_control(app.ControllerParams.Ti, app.ControllerParams.Ki);
                    
                    sys = feedback(G*F_PI,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PI); % System Disturbance 

                elseif app.ControllerParams.PI_design_flag
                    % get values from GUI
                    app.ControllerParams.phim = app.PhiMEditFieldPIDesign.Value;
                    app.ControllerParams.wc = app.OmegaCEditFieldPIDesign.Value;
                    
                    [F_PI, app.ControllerParams.Kp, app.ControllerParams.Ki, app.ControllerParams.Kd] = app.ControlFunctions.pi_design(G, app.ControllerParams.wc, app.ControllerParams.phim);
                    
                    sys = feedback(G*F_PI,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PI); % System Disturbance 
                elseif app.ControllerParams.P_controller_flag
                    app.ControllerParams.Kp = app.KpEditFieldPController.Value;
                    app.ControllerParams.Ki = 0;
                    app.ControllerParams.Kd = 0;

                    F_P = pid(app.ControllerParams.Kp);
                    sys = feedback(G*F_P,1);  % System General Transfer Function with G(s) and F_P(s)
                    sys_disturbance = feedback(G,F_P); % System Disturbance 

                elseif app.ControllerParams.PI_controller_flag
                    app.ControllerParams.Ki = app.KiEditFieldPIController.Value;
                    app.ControllerParams.Kp = app.KpEditFieldPIController.Value;
                    app.ControllerParams.Kd = 0;

                    F_PI = pid(app.ControllerParams.Kp, app.ControllerParams.Ki);
                    sys = feedback(G*F_PI,1);  % System General Transfer Function with G(s) and F_PI(s)
                    sys_disturbance = feedback(G,F_PI); % System Disturbance 

                elseif app.ControllerParams.PID_controller_flag
                    app.ControllerParams.Ki = app.KiEditFieldPIDController.Value;
                    app.ControllerParams.Kp = app.KpEditFieldPIDController.Value;
                    app.ControllerParams.Kd = app.KdEditFieldPIDController.Value;

                    F_PID = pid(app.ControllerParams.Kp, app.ControllerParams.Ki, app.ControllerParams.Kd);
                    sys = feedback(G*F_PID,1);  % System General Transfer Function with G(s) and F_PID(s)
                    sys_disturbance = feedback(G,F_PID); % System Disturbance 

                end
                
                progressbar_controller.Value = .67;
                progressbar_controller.Message = 'Calculating Disturbance Response';

                %% set step function params
                LOW__Ref_degree = round(app.ServoAngleLimitsSlider.Value(1));
                HIGH__Ref_degree = round(app.ServoAngleLimitsSlider.Value(2));                
                Danger_degree = 110;
                

                if app.StepButton.Value 
                    
                    % Calculate Time vector from settings
                    app.repetitions = app.StepRepetitionsSpinner.Value;
                    app.period = app.StepPeriodSpinner.Value; 
                    
                    [input_signal, t] = SignalSimulator.step_input(app,LOW__Ref_degree, HIGH__Ref_degree, app.period, app.repetitions);

                    % get one Step Iteration response for system stability info
                    [one_period, one_period_time] = SignalSimulator.step_input(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, 1);
                    
                    
                elseif app.RampButton.Value
                    app.repetitions = app.RampRepetitionsSpinner.Value;
                    app.period = app.RampPeriodSpinner.Value;

                    [input_signal, t] = SignalSimulator.symmetric_ramp_input(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, app.repetitions);
                    % Simulate the system response to the symmetric two-phase ramp input
                    % get one Step Iteration response for system stability info
                    [one_period, one_period_time] = SignalSimulator.symmetric_ramp_input(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, 1);
                    
                    
                elseif app.NaturalButton.Value
                    app.repetitions = app.NaturalRepetitionsSpinner.Value;
                    app.period = app.NaturalPeriodSpinner.Value; 

                    [input_signal, t] = SignalSimulator.natural_elbow_movement(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, app.repetitions);
                    
                    % Simulate the system response to the elbow movement
                    [one_period, one_period_time] = SignalSimulator.natural_elbow_movement(app, LOW__Ref_degree, HIGH__Ref_degree, app.period, 1);
                    
                end

                
                [Plot_Value, time]  = lsim(sys,input_signal, t);
                
                disturbance_rejection = lsim(sys_disturbance,input_signal, t);

                % add low_ref degree offset 
                Plot_Value = Plot_Value + LOW__Ref_degree;
                input_signal = input_signal + LOW__Ref_degree;
                
                progressbar_controller.Value = .75;
                progressbar_controller.Message = 'Checking Stability...';

                %% check slope of step response
                % Calculate slope
                dt = diff(time);
                dy = diff(Plot_Value);
                slope = dy ./ dt;
                
                % Calculate 10-point average slope
                window_size = 10;
                avg_slope = movmean(slope, window_size);
                % Find steepest point based on average slope
                [max_avg_slope, k_avg] = max(abs(avg_slope));
                                
                % Find steepest point based on instantaneous slope (as before)
                [max_slope, k] = max(abs(slope));
                
             
                if (max_avg_slope || max_slope) > 500
                    report = ["Step response Slope is too step!"];
                    % "10-point average slope:";
                    %             "Steepest point occurs at t = " num2str(t(k_avg));
                    %             "Maximum average slope = " num2str(max_avg_slope)];
                    uialert(app.CLASSROOMEXO_Figure,report,"Warning", "Icon","warning");
                end

                %% check for stability and get graph info FOR STEP RESPONSE ONLY
                % get graph info for the first period of the input signal
                % i.e. the first step, ramp or first complete sine wave
                % cut the time, and input vector accordingly
                first_period_time = time(time>app.period/2 & time<(3*app.period/4));
                first_period_input = Plot_Value(time>app.period/2 & time<(3*app.period/4));
                
                STEP_respOpt = RespConfig('InputOffset',LOW__Ref_degree,'Amplitude',HIGH__Ref_degree);
                [one_period_sys, one_period_time] = step(sys, STEP_respOpt);
                Sys_info = stepinfo(one_period_sys, one_period_time, HIGH__Ref_degree, LOW__Ref_degree);
                Sys_info = stepinfo(sys, 'RiseTimeLimits',[0 0.63]);
                S = lsiminfo(first_period_input, first_period_time, HIGH__Ref_degree, LOW__Ref_degree);
                
                if app.ControllerParams.PID_design_flag || app.ControllerParams.PID_control_flag
                    % app.Stable_Factor = isstable(sys);  % check the system's stabiliy
                    % -> this is wrong! it checks the stability of a filter. Why was this used?
                    app.Stable_Factor = 1;
                elseif app.ControllerParams.PI_design_flag || app.ControllerParams.PI_control_flag
                    app.Stable_Factor = 1;
                end

                Ref = Plot_Value(end);
                

                % Step Response Plot 
                app.ServoPlotManager.updateStepResponse(time, Plot_Value, input_signal);
                

                % Step Disturbance Plot
                app.ServoPlotManager.updateStepDisturbance(time, Plot_Value, disturbance_rejection);
                % % calculate and adjust upper and lower plot bounds
                % upper_bounds = round(max(disturbance_rejection)+(mean(disturbance_rejection)/2),1);
                % lower_bounds = round(min(disturbance_rejection)-(mean(disturbance_rejection)/2), 1);
                % if upper_bounds <= 0.5
                %     upper_bounds = 0.5;
                % elseif lower_bounds > -0.1 && lower_bounds < 0.0
                %     lower_bounds = -0.5;
                % end
                % hold(app.StepDisturbance,'on');
                % app.StepDisturbance.XLim = [0 length(time)];
                % % app.StepDisturbance.YLim = [lower_bounds upper_bounds];
                % % Place ticks every second
                % app.StepDisturbance.XTick = 0:50:length(time);
                % % app.StepDisturbance.YTick = lower_bounds:(upper_bounds/10):upper_bounds;
                % app.StepDisturbance.XTickLabel = 0:(0.02*50):max(time);
                % % app.StepDisturbance.YTickLabel = lower_bounds:(upper_bounds/10):upper_bounds;
                % app.StepDisturbance.YGrid = 'on';
                % x_values = linspace(1,length(time), length(disturbance_rejection));
                % line(app.StepDisturbance,x_values', disturbance_rejection);
                
                progressbar_controller.Value = 1;
                progressbar_controller.Message = 'Finishing';

                %%Transfer function Plot
                %% -> moved to live plott!!
                app.ServoPlotManager.updateServoTrajectory(time, Plot_Value, HIGH__Ref_degree);
                % app.PLOT_degree = (HIGH__Ref_degree*Plot_Value)/Ref; % convert curve value to degrees
                app.PLOT_degree = Plot_Value; % conversion is obsolete!
                
                % line(app.ServoTrajectory, time', app.PLOT_degree); %% this line will be moved to live plotting!!!
                % hold(app.ServoTrajectory,'on');
                % app.ServoTrajectory.XLim = [0 length(time)];
                % app.ServoTrajectory.YLim = [0 Danger_degree+10];
                % app.ServoTrajectory.XTick = 0:50:length(time);
                % app.ServoTrajectory.YTick = 0:10:(Danger_degree+10);
                % app.ServoTrajectory.XTickLabel = 0:(0.02*50):max(time);
                % app.ServoTrajectory.YTickLabel = 0:10:(Danger_degree+10);
                % app.ServoTrajectory.YGrid = 'on';
                % 
                % %%Ploting the Red zone
                % X_End_point = app.ServoTrajectory.XTick(end);
                % Y_End_point = app.ServoTrajectory.YTick(end);
                % X_values = 1:X_End_point;
                % Danger_degree_threshold = Danger_degree.* ones(1,X_End_point);
                % Max_Value = Y_End_point.* ones(1,X_End_point);
                % Ref_point_line = app.PLOT_degree(end).* ones(1,X_End_point);
                % 
                % yline(app.ServoTrajectory, HIGH__Ref_degree, 'LineStyle', ':', 'Color', 'black');
                % yline(app.ServoTrajectory, Danger_degree_threshold, 'LineWidth', 2, 'Color', 'red');
                % % line(app.ServoTrajectory,X_values,Ref_point_line,":",'LineWidth',1,"Color","black"); %[0.8 0.8 0.8]
                % % line(app.ServoTrajectory,X_values,Danger_degree_threshold,"red",'LineWidth',2);
                % 
                % % check if any element in the Plot vector exceeds the danger degree
                % % Fill the Danger area red if true
                % if any(app.PLOT_degree>= Danger_degree)
                %     app.Danger = 1;    
                %     %%Red zone fill
                %     x = 0 : X_End_point;
                %     curve1 = Danger_degree.* ones(1,length(x));
                %     curve2 = Y_End_point.* ones(1,length(x));
                % 
                %     x2 = [x, fliplr(x)];
                %     inBetween = [curve1, fliplr(curve2)];
                %     fill(app.ServoTrajectory,x2, inBetween, 'r','FaceAlpha',0.5, 'EdgeColor','none'); 
                % end


                % show parameters only with two significant numbers!
                % (changed from 4 to 2)
                
                app.TransientTimeLabel.Text = 'Transient Time [s] :   ' + string(round(Sys_info.TransientTime,2));
                app.SettlingTimeLabel.Text = 'Settling Time [s]   :   ' + string(round(Sys_info.SettlingTime,2)');
                app.OverShootLabel.Text = 'Overshoot [%]      :   ' + string(round(Sys_info.Overshoot,2));
                app.PeakLabel.Text = 'Peak [deg]             :   ' + string(round(Sys_info.Peak*HIGH__Ref_degree,2));
                % Time Consnat is wrong -> it should be the time until 63%
                % of peak were reached?
                app.TimeConstantLabel.Text = 'Time Constant [s] :   ' + string(round((Sys_info.RiseTime),2));

                Poles = pole(sys);
                Zeros = zero(sys);
                app.PolesLabel.Text = 'Poles      :   ' + string(round(Poles,2));
                app.ZerosLabel.Text = 'Zeros      :   ' + string(round(Zeros,2));
                
                legend(app.ServoTrajectory,{'Servo Refrence Angle','Risk threshold'},'Location','southeast')
                
                %%%%%% RGB Switch Colours%%%%%%
                value = app.ServoPowerSwitch.Value;
                if app.connectFlag == true && value == "Off"
                    %% RGB (white)
                    app.CommFunctions.RGB_Colour(app.color_red);
                end

                if max(app.PLOT_degree)>= Danger_degree && min(app.PLOT_degree)<= 0
                    report = "The used system (TF) is crosses the both Danger zones (110° and 0°). Please Try another system or change the Maximum and Minimum Servo Angle Limits";
                    uialert(app.CLASSROOMEXO_Figure, report,"Unstable System","Icon","warning");
                    app.UIStateManager.changeTransferFunctionTab('initial');
                    app.Danger = 1;
                elseif max(app.PLOT_degree)>= Danger_degree 
                    report = "The used system (TF) is crosses the Danger zone (110°). Please Try another system or change the Maximum Servo Angle Limits";
                    uialert(app.CLASSROOMEXO_Figure, report,"Unstable System","Icon","warning");
                    app.UIStateManager.changeTransferFunctionTab('initial');
                    app.Danger = 1;
                elseif min(app.PLOT_degree)<= 0
                    report = "The used system (TF) is crosses the Danger zone (0°). Please Try another system or change the Minimum Servo Angle Limits";
                    uialert(app.CLASSROOMEXO_Figure, report,"Unstable System","Icon","warning");
                    app.UIStateManager.changeTransferFunctionTab('initial');
                    app.Danger = 1;
                end
                
                app.RefreshButton.Enable = "on";
                app.RefreshButton_3.Enable = "on";
                

                if (app.charge_status || app.connectFlag) && app.Danger == 0
                    app.UIStateManager.changeServoControlPanel('enable');
                    app.ExecuteButton.Enable = "off";
                    app.Servo_Plot_Reset.Enable = "off";                
                end
                close(progressbar_controller);

            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure,report,"Error", "Icon","error");

                %%%%%% RGB Switch Colours%%%%%%
                value = app.ServoPowerSwitch.Value;
                if app.connectFlag == true && value == "Off"
                    %% RGB (red)
                    app.CommFunctions.RGB_Colour(app.color_red);
                end
                
            end
            
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.RefreshButton.Enable = "off";

            clc
            if (~app.Compare_changes_flag)
                if app.PI_control_flag 
                    app.KiEditFieldPIControl.Value = 1;
                    app.TiEditFieldPIControl.Value = 1;
                elseif app.PI_design_flag
                    app.PhiMEditFieldPIDesign.Value = 45;
                    app.OmegaCEditFieldPIDesign.Value = 1.5;               
                elseif app.PID_control_flag
                    app.ZetaEditFieldPIDControl.Value = 1;
                    app.BetaEditFieldPIDControl.Value = 1;
                    app.KiEditFieldPIControl.Value = 1;
                    app.TauEditFieldPIDControl.Value = 1;
                elseif app.PID_design_flag
                    app.ZetaEditFieldPIDDesign.Value = 1;
                    app.OmegaCEditFieldPIDDesign.Value = 1;
                    app.BetaEditFieldPIDDesign.Value = 1;
                    app.PhiMEditFieldPIDDesign.Value = 1;
                end

            else
                if app.PI_control_flag 
                    app.KiEditFieldPIControl.Value = 1;
                    app.TiEditFieldPIControl.Value = 1;
                elseif app.PI_design_flag
                    app.PhiMEditFieldPIDesign.Value = 45;
                    app.OmegaCEditFieldPIDesign.Value = 1.5;               
                elseif app.PID_control_flag
                    app.ZetaEditFieldPIDControl.Value = 1;
                    app.BetaEditFieldPIDControl.Value = 1;
                    app.KiEditFieldPIControl.Value = 1;
                    app.TauEditFieldPIDControl.Value = 1;
                elseif app.PID_design_flag
                    app.ZetaEditFieldPIDDesign.Value = 1;
                    app.OmegaCEditFieldPIDDesign.Value = 1;
                    app.BetaEditFieldPIDDesign.Value = 1;
                    app.PhiMEditFieldPIDDesign.Value = 1;
                end

                app.TransientTimeLabel.Text = "Transient Time:";
                app.TimeConstantLabel.Text = "Time Constant  :";
                app.SettlingTimeLabel.Text = "Settling Time    :";
                app.OverShootLabel.Text = "OverShoot        :";
                app.PeakLabel.Text = "Peak                  :";
                
                app.ZerosLabel.Text = "Zeros          :";
                app.PolesLabel.Text = "Poles          :";
                app.ServoSpeedKnob.Value = '1';
            end

            app.UIStateManager.changeTransferFunctionTab('enable');
            app.RefreshButton.Enable = "off";
        end

        % Callback function
        function ExecuteButtonPushed(app, event)
            try
                legend(app.ServoTrajectory,'off');
                CheckBattery(app,1);
                app.charge_status = 1; % only temp for debug
                app.StatusLamp.Color = [255,55,180]./255;
                app.Label.Text = 'PID mode';
                
                % disable all buttons
                app.UIStateManager.changeTransferFunctionTab('disable');
                app.UIStateManager.changeServoControlPanel('disable');

                if app.Stable_Factor == 1  && app.Danger == 0 && app.charge_status == 1
                    
                    progressbar_test = uiprogressdlg(app.CLASSROOMEXO_Figure, 'Title','Please Wait','Message','Zeroing Servo', 'Icon','info');
                    drawnow;
                    
                    %%%%%% RGB Error Colours %%%%%%
                    %% RGB (white)
                    %app.CommFunctions.RGB_Colour(app.color_white);
                    app.Servo_Speed = str2num(app.ServoSpeedKnob.Value);
                    speed = 6 - app.Servo_Speed;
                    speed = 3;
                    stepsize = 10;
                    LOW_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(1)'*10);
                    HIGH_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(2)'*10);
                    uint16_LOW_Ref_degree = uint16(LOW_Ref_degree);
                    app.CommFunctions.resetServoPosition(uint16_LOW_Ref_degree, speed, stepsize);
                    
% todo: get current servo motor position!!!!

                    deltaT = (speed * 0.05) * ((LOW_Ref_degree-HIGH_Ref_degree)/stepsize)

                    pause(deltaT);
                    progressbar_test.Message = "Sending Data to Exo";
                    progressbar_test.Value = 0.25;
                    % get plot values (angles)
                    % h = findobj(app.ServoTrajectory, 'type', 'line');
                    % Plot_Value = h(end).YData;

                    app.ServoTrajectory.NextPlot = 'add';  % move up to startfcn?
                    

                    % app.EMG_CH1.axh()
                    hold(app.ServoTrajectory, "on");
                    h = animatedline(app.ServoTrajectory, nan, nan, 'LineStyle', '-');
                    h2 = animatedline(app.ServoTrajectory, nan, nan, 'LineStyle', '--', 'Color', 'cyan');
                    
                    
                    speed = 5 - app.Servo_Speed
                    app.CommFunctions.setServoSpeed(speed);
                    

                    %% interpolate data to 200 data points
                    %Interpolate PlotValue to 200 points
                    t = linspace(0, 1, numel(app.PLOT_degree));
                    t_ = linspace(0, 1, 200);
                    % app.PLOT_degree = interp1(t, app.PLOT_degree, t_, 'linear'); 

                    T = app.period + (app.repetitions * app.period);% Total time duration of motion in seconds, add half period at start and end of the input signal
                    dt = 0.02; % Time step
                    time_vector = 0:dt:T; % Time vector
                    
                    
                    Max_Degree= 120;
                    %angle = typecast(round((180 - Plot_Value)), 'uint8');
                    % angle = uint16((1800 - (Plot_Value*10)));
                    angle = uint16((1800 - (app.PLOT_degree'*10)));
                    % angle = angle';

                    % split the angle vector into smaller parts (for sending)
                    % Desired chunk size
                    % bigger chuncks resulted in dataloss!! 
                    uint8_chunkSize = 26;
                    uint16_chunkSize = 13;
                    
                    
                    % Calculate the number of chunks
                    numChunks = ceil(length(angle) / uint16_chunkSize);
                    % set the pid package counter to the number of chunks
                    app.CommFunctions.setPIDpackagecounter(uint8(numChunks));

                    error_flag = true;

                    % flush device before sending/receiving data
                    flush(app.connected_device, "input");
                    flush(app.connected_device, "output");
                    app.connected_device.NumBytesAvailable
                    

                    % send Data Chunk to the Exoskeleton
                    % also: Update progressbar after each chucnk was sent,
                    % to show sending process in app
                    for i = 1:numChunks
                        % Determine the start and end indices for the current chunk
                        startIndex = (i - 1) * uint16_chunkSize + 1;
                        endIndex = min(i * uint16_chunkSize, length(angle));
                        progressbar_test.Value = i/numChunks;
                        if error_flag
                            % Extract the current chunk of data
                            uint16_currentChunk = angle(startIndex:endIndex);
                            uint8_CurrentChunk = typecast(uint16_currentChunk, 'uint8');
                        
                            % Display or send the current chunk of data
                            disp(['Sending Chunk uint16 ' , num2str(i), ': ', num2str(uint16_currentChunk)]);
                            disp(['Sending Chunk ', num2str(i), ': ', num2str(uint8_CurrentChunk)]);
                            
                            [error_flag]= app.CommFunctions.moveServoPID(length(uint8_CurrentChunk), uint8_CurrentChunk);
                            pause(0.1);
                        

                        elseif (error_flag == false)
                            % Extract the current chunk of data
                            uint16_currentChunk = angle(startIndex:endIndex);
                            uint8_CurrentChunk = typecast(uint16_currentChunk, 'uint8');
                        
                            % Display or send the current chunk of data (replace this with your actual sending logic)
                            disp(['Sending Chunk uint16 ', num2str(i), ': ', num2str(uint16_currentChunk)]);
                            disp(['Sending Chunk ', num2str(i), ': ', num2str(uint8_CurrentChunk)]);
                            
                            [error_flag]= app.CommFunctions.moveServoPID(length(uint8_CurrentChunk), uint8_CurrentChunk);
                            pause(0.1);
                        end
                    end

                    close(progressbar_test);
                
                    %servo_angles = string(microseconds);
                    Analog_voltage = [];
                    
                    a = tic; % start timer
                    for i=1:length(angle)-2
                        
                        %% get the servo Analog degree feedback from the 4th wire in the servo
                        
                        [~, temp] = app.CommFunctions.getServoAngleStream();
                        temp_plot = ((1800-temp)/10);
                        i;
                        
                       
                        % hold(app.ServoTrajectory, 'on')
                        hold(app.ServoTrajectory, 'on');
                        addpoints(h, time_vector(i), app.PLOT_degree(i));
                        addpoints(h2, time_vector(i), temp_plot);
                        hold(app.ServoTrajectory, 'off');
    
                        % drawnow;
                        
                        
                    end
                    % legend(app.ServoTrajectory,{'Servo Refrence Angle','Risk threshold', 'Servo Trajectory', 'Servo Feedback'},'Location','southeast')
                    legend(app.ServoTrajectory, [h h2], {'Servo Trajectory', 'Servo Feedback'},'Location','northeast')
                        
                    
                    % Analog_bit=Analog_voltage;
                     % y = (x - inMin) / (inMax - inMin) * (outMax - outMin) + outMin
                    %% moved to arduino!
                    % Analog_degree = (Analog_voltage - 850)/(2050-850).*(120-30) + 30;
                    % Analog_degree = (180/(266-738)).*(Analog_bit-738);
                    % servo_feedback = 180-Analog_degree; % 200 is an offset
                    
                    % enable reset Button
                    app.Servo_Plot_Reset.Enable = "on";
                    
                    app.angle_END = angle(end);
                    
                    % pause(0.5);
                    CheckBattery(app,2);
                    %clear connected_servo
                    %%writeread(connected_device, home_servo);
                    app.charge_status=0;
                    app.UIStateManager.changeTransferFunctionTab('initial');
                    app.Servo_Plot_Reset.Visible = "on";

                else
                    report = "The used system (TF) is unstable or may corss the Danger zone . Please Try another system or change the Ref. Degree";
                    uialert(app.CLASSROOMEXO_Figure, report,"Unstable System","Icon","warning");
                    %%%%%% RGB Error Colours %%%%%%
                    %% RGB (red)
                    % app.CommFunctions.RGB_Colour(app.color_red);
                    % app.charge_status=0;
                end

            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error");
                app.Servo_Plot_Reset.Enable = "off";
                %%%%%% RGB Error Colours %%%%%%
                 %% RGB (red)
                app.CommFunctions.RGB_Colour(app.color_red);
                app.charge_status=0;
            end
        end

        % Button pushed function: Servo_Plot_Reset
        function Servo_Plot_ResetPushed(app, event)
            
            try
                app.Servo_Plot_Reset.Enable = "off";
                % connected_servo = servo(connected_device,'D5', 'MaxPulseDuration', 0.00205, 'MinPulseDuration',0.00085); % Connect the servo values to Matlab through Arduino
                CheckBattery(app,2);
                
                app.Servo_Speed = str2double(app.ServoSpeedKnob.Value);
                speed = 5 - app.Servo_Speed;
                speed = 1;
                stepsize = 1;
                LOW_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(1)'*10);
                HIGH_Ref_degree = 1800 - (app.ServoAngleLimitsSlider.Value(2)'*10);
                uint16_LOW_Ref_degree = uint16(LOW_Ref_degree);
                app.CommFunctions.resetServoPosition(uint16_LOW_Ref_degree, speed, stepsize);
                
                delete(findobj(app.ServoTrajectory, 'Color','c'))
                delete(findobj(app.ServoTrajectory, 'Color','k'));
                legend(app.ServoTrajectory,{'Risk threshold'},'Location','southeast')
                
                deltaT = (speed * 0.05) * ((LOW_Ref_degree - HIGH_Ref_degree)/stepsize);
                
                pause(deltaT);

                app.UIStateManager.changeServoControlPanel('enable');
                app.Servo_Plot_Reset.Enable = "off";
                
            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure,report,"Error","Icon","error");
            end
        end

        % Value changed function: ServoPowerSwitch
        function ServoPowerSwitchValueChanged(app, event)
            value = app.ServoPowerSwitch.Value;
            CheckBattery(app,1);
            app.charge_status = 1; % only for debug!!!
            if ((value == "On") && (app.charge_status == 1))
                
                app.CommFunctions.setServoState(0x52);
                
                app.UIStateManager.changeServoControlPanel('initial ');
                app.ExecuteButton.Enable = "on";
                app.ServoSpeedKnob.Enable = "on";
                
            else
                app.CommFunctions.setServoState(0x53);

                app.UIStateManager.changeServoControlPanel('disable');
                app.UIStateManager.changeTransferFunctionTab('initial');
                app.ServoPowerSwitch.Value = "Off";
                app.ServoPowerSwitch.Enable = "on";

                report = ["Servo Power turned off", "The Enable Pin of the Servo motor is turned off."];
                uialert(app.CLASSROOMEXO_Figure, report, "Information","Icon","info");
                
            end
            
        end

        % Value changed function: CH2DropDown
        function CH2DropDownValueChanged(app, event)
            Channel_Port = app.CH2DropDown.Value;
 
            if strcmp(Channel_Port, 'EMG0')
                app.analogEMG0Pin = 2;
                app.analogEMG1Pin = 1;
                app.CH1DropDown.Value = app.CH1DropDown.Items{1,2};
            else
                app.analogEMG0Pin = 1;
                app.analogEMG1Pin = 2;
                app.CH1DropDown.Value = app.CH1DropDown.Items{1,1};

            end
        end

        % Value changed function: EmergnecySTOPButton
        function EmergnecySTOPButtonValueChanged(app, event)
           try 
                
                % Set Arduino to emergency Stop Mode -> manually turn off
                % arduino
                app.CommFunctions.emergencyStop();

                h= findobj(app.CLASSROOMEXO_Figure,'-property','Enable');
                
                set(h,'Enable','off');

                report = ["Emergency Stop"; "System disconnected and diasbled. Please restart Program and manually turn off power to the Edu Exo Pro!"];
                uialert(app.CLASSROOMEXO_Figure, report, "Warning!", "Icon", "warning");
                
                app.CommManager.cleanup();

                clear all;


            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error");
            end
            

        end

        % Value changed function: StartRecordingSwitch_DualThresh_Force
        function StartRecordingSwitch_DualThresh_ForceValueChanged(app, event)
            value = app.StartRecordingSwitch_DualThresh_Force.Value;
            try
                if strcmp(value, 'On')
                    app.StatusLamp.Color = [50,255,255]./255;
                    app.Label.Text = 'Force control';
                    % Set initial thresholds
                    app.imuPlotManagerForce.setThresholdsActive('force', app.ForceUpperThresholdSlider.Value, app.ForceLowerThresholdSlider.Value);
            
                    % Set initial visibility
                    app.imuPlotManagerForce.setThresholdsVisible('force', app.ForceShowThresholdCheckBox.Value);
                    app.UIStateManager.changeForceControlTab('running');
                    app.upper_threshold = uint16(app.ForceUpperThresholdSlider.Value/(17/4));
                    app.lower_threshold = uint16(abs(app.ForceLowerThresholdSlider.Value/(17/4)));
                    speed = 5 - uint8(str2double(app.SpeedSetting_Force.Value));
                    app.CommFunctions.setServoSpeed(speed);
                    
                    switch app.ForceControlMode
                        case 'standard'
                            app.CommFunctions.setThresholds(0x41, app.upper_threshold, app.lower_threshold)
                        case 'proportional'
                            gain = single(app.proportionalgainSlider.Value);
                            app.CommFunctions.setThresholds(0x43, app.upper_threshold, app.lower_threshold,gain)
                    end

                    app.imuPlotManagerForce.start('force');
                else
                    app.imuPlotManagerForce.stop();
                    app.UIStateManager.changeForceControlTab('initial');
                    CheckBattery(app,2);
                end
            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error");
            end
            

        end

        % Clicked callback: BTDeviceDropDown
        function BTDeviceDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            app.selectFlag = true;
        end

        % Value changed function: ControlDropDown
        function ControlDropDownValueChanged(app, event)
            mode = app.ControlDropDown.Value;

            app.UIStateManager.changeControllerDropDownMenu(mode);

            
        end

        % Button pushed function: OptimizePIButton
        function OptimizePIButtonPushed(app, event)
            % This function was implemented by Mohmand based on the functions in the book!
            progressbar_PIopt = uiprogressdlg(app.CLASSROOMEXO_Figure,'Title','Please Wait','Message','Calculating optimized values', 'Icon','info');
           
            %%%%%%%%  G(s) Plant Transfer Funtion %%%%%%%%%%
            % G(S) Num Values
            G_num = convertCharsToStrings(app.numEditField.Value);
            G_den = convertCharsToStrings(app.denEditField.Value);
                            
            G = app.ControlFunctions.analyze_transfer_function(G_num, G_den) % create the G(s) plant
  
            progressbar_PIopt.Value = .33;
            [gain,phase,w]=bode(G);     % Bodediagram
            phase = squeeze(phase);
            w150=interp1(phase,w,-150);    %-150                                    % Beräknar ωG150
            [F,wc,phim,Ki,Ti,MS,MT]= app.ControlFunctions.pi_opt(G,0.3*w150,0.5*w150,45,60);
            progressbar_PIopt.Value = .67;
            phim = ceil(phim);
            phim_end = phim+1;
            phim_start = phim-2;
            
            wc = wc*10;
            wc = ceil(wc);
            wc = wc/10;
            wc_end = wc+0.02;
            wc_start = wc-0.03;

            [F,wc,phim,Ki,Ti,MS,MT]= app.ControlFunctions.pi_opt(G,wc_start,wc_end,phim_start,phim_end);                             % Optimal PI-reg.
            progressbar_PIopt.Value = 1;
            progressbar_PIopt.Message = 'Finishing';

            if app.ControllerParams.PI_control_flag
                app.TiEditFieldPIControl.Value = Ti;
                app.KiEditFieldPIControl.Value = Ki;
            elseif app.ControllerParams.PI_design_flag
                app.PhiMEditFieldPIDesign.Value = phim;
                app.OmegaCEditFieldPIDesign.Value = wc;
            end
            close(progressbar_PIopt);
        end

        % Value changed function: ForceShowThresholdCheckBox
        function ForceShowThresholdCheckBoxValueChanged(app, event)
            app.imuPlotManagerForce.setThresholdsVisible('force', app.ForceShowThresholdCheckBox.Value);
        end

        % Value changed function: StartRecordingSwitch_DualThresh_EMG
        function StartRecordingSwitch_DualThresh_EMGValueChanged(app, event)
            value = app.StartRecordingSwitch_DualThresh_EMG.Value;
            
            try
                if strcmp(value, 'On')
                    app.StatusLamp.Color = [175,0,255]./255;
                    app.Label.Text = 'EMG control';
                    % Set initial thresholds
                    app.imuPlotManagerEMG.setThresholdsActive('emg', app.EMGUpperThresholdSlider.Value, app.EMGLowerThresholdSlider.Value);
            
                    % Set initial visibility
                    app.imuPlotManagerEMG.setThresholdsVisible('emg', app.EMGShowThresholdCheckBox.Value);
                    app.EMG_Upper_Threshold = uint16((app.EMGUpperThresholdSlider.Value)*3.23);
                    app.EMG_Lower_Threshold = uint16((app.EMGLowerThresholdSlider.Value)*3.23);
                    speed = 5 - uint8(str2double(app.SpeedSetting_EMG.Value));
                    app.CommFunctions.setServoSpeed(speed);
                    app.CommFunctions.setThresholds(0x40, app.EMG_Upper_Threshold, app.EMG_Lower_Threshold);
                    app.CommFunctions.setAnalogPin(0x40, uint8(app.analogEMG0Pin))
                    app.UIStateManager.changeEMGControlTab('running');
                    app.imuPlotManagerEMG.start('emg');
                    
                    
                else
                    app.imuPlotManagerEMG.stop();
                    app.UIStateManager.changeEMGControlTab('initial');
                    CheckBattery(app,2);
                end
            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error");
            end
        end

        % Value changed function: EMGShowThresholdCheckBox
        function EMGShowThresholdCheckBoxValueChanged(app, event)
            
            app.imuPlotManagerEMG.setThresholdsVisible('emg', app.EMGShowThresholdCheckBox.Value);
        end

        % Value changed function: ApplyThresholdsButton_EMG
        function ApplyThresholdsButton_EMGValueChanged(app, event)
            % Set initial thresholds
            app.imuPlotManagerEMG.setThresholdsActive('emg', app.EMGUpperThresholdSlider.Value, app.EMGLowerThresholdSlider.Value);
            
            app.EMG_Upper_Threshold = uint16((app.EMGUpperThresholdSlider.Value)*3.23);
            app.EMG_Lower_Threshold = uint16((app.EMGLowerThresholdSlider.Value)*3.23);
            

            speed = 5 - uint8(str2double(app.SpeedSetting_EMG.Value));
            stepsize = uint8(10);

            app.imuPlotManagerEMG.stop();
            app.imuPlotManagerEMG.clearPlots();
            app.CommFunctions.setThresholds(0x40, app.EMG_Upper_Threshold, app.EMG_Lower_Threshold); 
            app.imuPlotManagerEMG.setThresholdsVisible('emg', app.ForceShowThresholdCheckBox.Value);
           
            app.imuPlotManagerEMG.start('emg');
        end

        % Value changed function: ApplyThresholdsButton_Force
        function ApplyThresholdsButton_2ValueChanged(app, event)
            % Set initial thresholds
            app.imuPlotManagerForce.setThresholdsActive('force', app.ForceUpperThresholdSlider.Value, app.ForceLowerThresholdSlider.Value);
            
            app.upper_threshold = uint16(app.ForceUpperThresholdSlider.Value/(17/4));
            app.lower_threshold = uint16(abs(app.ForceLowerThresholdSlider.Value/(17/4)));
            
            speed = 5 - uint8(str2double(app.SpeedSetting_Force.Value));
            stepsize = uint8(10);
            
            app.imuPlotManagerForce.stop();
            app.imuPlotManagerForce.clearPlots();
            app.CommFunctions.setThresholds(0x41, app.upper_threshold, app.lower_threshold)
            app.imuPlotManagerForce.setThresholdsVisible('force', app.ForceShowThresholdCheckBox.Value);
           
            app.imuPlotManagerForce.start('force');
            
        end

        % Value changed function: TrackChangesCheckBox
        function TrackChangesCheckBoxValueChanged(app, event)
            app.ControllerParams.Compare_changes_flag = app.TrackChangesCheckBox.Value;
            
        end

        % Value changed function: StartRecordingSwitch_IMU
        function StartRecordingSwitch_IMUValueChanged(app, event)
            value = app.StartRecordingSwitch_IMU.Value;
            try
                if strcmp(value, 'On')
                    app.StatusLamp.Color = "m";
                    app.imuPlotManager.start('imu');
                    app.imuPlotManager.acquireAndUpdateData();
                % app.PlotManager.start({'force'});
                else
                    app.imuPlotManager.stop();
                % app.PlotManager.stop();
                end
            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error");
            end
        end

        % Value changed function: ServoAngleLimitsSlider
        function ServoAngleLimitsSliderValueChanged(app, event)
            AdjustServoLimitsSliderGUI(app)
        end

        % Value changed function: HighRefDegreeEditField
        function HighRefDegreeEditFieldValueChanged(app, event)
            AdjustServoLimitsGUI(app)
        end

        % Value changed function: LowRefDegreeEditField
        function LowRefDegreeEditFieldValueChanged(app, event)
            AdjustServoLimitsGUI(app)
        end

        % Value changed function: EMGUpperThresholdSlider
        function EMGUpperThresholdSliderValueChanged(app, event)
            app.UIStateManager.AdjustThresholdsGUI('EMG', 'Slider');
        end

        % Value changed function: EMGLowerThresholdSlider
        function EMGLowerThresholdSliderValueChanged(app, event)
            app.UIStateManager.AdjustThresholdsGUI('EMG','Slider');
        end

        % Value changed function: EMGUpperThreshold
        function EMGUpperThresholdValueChanged(app, event)
           app.UIStateManager.AdjustThresholdsGUI('EMG','Display');
        end

        % Value changed function: EMGLowerThreshold
        function EMGLowerThresholdValueChanged(app, event)
            app.UIStateManager.AdjustThresholdsGUI('EMG','Display');
        end

        % Value changed function: ForceUpperThresholdSlider
        function ForceUpperThresholdSliderValueChanged(app, event)
            app.UIStateManager.AdjustThresholdsGUI('Force','Slider');
        end

        % Value changed function: ForceLowerThresholdSlider
        function ForceLowerThresholdSliderValueChanged(app, event)
            app.UIStateManager.AdjustThresholdsGUI('Force','Slider');            
        end

        % Button pushed function: OptimizePIDButton
        function OptimizePIDButtonPushed(app, event)
            report = "Function not implemented!";
            uialert(app.CLASSROOMEXO_Figure,report,"Information","Icon","info");
        end

        % Value changed function: ShowInputSignalCheckBox
        function ShowInputSignalCheckBoxValueChanged(app, event)
            app.ControllerParams.Compare_changes_flag = app.TrackChangesCheckBox.Value;
            app.ControllerParams.ShowInputSignal_flag = app.ShowInputSignalCheckBox.Value;
            
        end

        % Value changed function: StepPeriodSpinner, StepRepetitionsSpinner
        function StepPeriodSpinnerValueChanged(app, event)
            cla(app.InputSignalGraph, 'reset')
            app.period = app.StepPeriodSpinner.Value;
            app.repetitions = app.StepRepetitionsSpinner.Value;
                       
            [input_signal, t] = SignalSimulator.step_input(app,app.LowRefDegreeEditField.Value, app.HighRefDegreeEditField.Value, app.period, app.repetitions);
            input_signal = input_signal+app.LowRefDegreeEditField.Value;
            hold(app.InputSignalGraph,'on');
            plot(app.InputSignalGraph, t, input_signal);
            % app.InputSignalGraph.XLim = [0 length(t)];
            app.InputSignalGraph.XLim = [0, max(t)];
            app.InputSignalGraph.XTick = 0:10:length(t);
            % app.InputSignalGraph.XTickLabel = 0:0.2*10:max(t);
            app.InputSignalGraph.XLabel.String = "Time [s]";
            app.InputSignalGraph.YLabel.String = "Amplitude [°]";
            app.InputSignalGraph.Title.String = "Input Signal";

        end

        % Value changed function: RampPeriodSpinner, RampRepetitionsSpinner
        function RampPeriodSpinnerValueChanged(app, event)
            cla(app.InputSignalGraph, 'reset')
            app.period = app.RampPeriodSpinner.Value;
            app.repetitions = app.RampRepetitionsSpinner.Value;
                       
            [input_signal, t] = SignalSimulator.symmetric_ramp_input(app,app.LowRefDegreeEditField.Value, app.HighRefDegreeEditField.Value, app.period, app.repetitions);
            input_signal = input_signal+app.LowRefDegreeEditField.Value;
            hold(app.InputSignalGraph,'on');
            plot(app.InputSignalGraph, t, input_signal);
            app.InputSignalGraph.XLim = [0, max(t)];
            app.InputSignalGraph.XTick = 0:10:length(t);
            app.InputSignalGraph.XLabel.String = "Time [s]";
            app.InputSignalGraph.YLabel.String = "Amplitude [°]";
            app.InputSignalGraph.Title.String = "Input Signal";
            
            
        end

        % Value changed function: NaturalPeriodSpinner, 
        % ...and 1 other component
        function NaturalPeriodSpinnerValueChanged(app, event)
            cla(app.InputSignalGraph, 'reset')
            app.period = app.NaturalPeriodSpinner.Value;
            app.repetitions = app.NaturalRepetitionsSpinner.Value;
                       
            [input_signal, t] = SignalSimulator.natural_elbow_movement(app,app.LowRefDegreeEditField.Value, app.HighRefDegreeEditField.Value, app.period, app.repetitions);
            input_signal = input_signal+app.LowRefDegreeEditField.Value;
            hold(app.InputSignalGraph,'on');
            plot(app.InputSignalGraph, t, input_signal);
            app.InputSignalGraph.XLim = [0, max(t)];
            app.InputSignalGraph.XTick = 0:10:length(t);
            app.InputSignalGraph.XLabel.String = "Time [s]";
            app.InputSignalGraph.YLabel.String = "Amplitude [°]";
            app.InputSignalGraph.Title.String = "Input Signal";
            
            
        end

        % Button pushed function: RefreshButton_Connection_List
        function RefreshButton_Connection_ListPushed(app, event)
            app.UIStateManager.changeConnectionTab('initial');
            startupFcn(app);
        end

        % Value changed function: EMGModeSwitch
        function EMGModeSwitchValueChanged(app, event)
            if strcmpi(app.EMGModeSwitch.Value, "Bi EMG")
                app.BiChannel_flag = true;
                app.UIStateManager.changeEMGControlTab('bi-emg');
            else 
                app.BiChannel_flag = false;
                app.UIStateManager.changeEMGControlTab('mono-emg');
            end
            app.imuPlotManagerEMG.Plots.emg.setSecondSensorVisible(app.BiChannel_flag);
            
        end

        % Value changed function: CH1DropDown
        function CH1DropDownValueChanged(app, event)
            Channel_Port = app.CH1DropDown.Value;
 
            if strcmp(Channel_Port, 'EMG0')
                app.analogEMG0Pin = 1;
                app.analogEMG1Pin = 2;
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,2};
            else
                app.analogEMG0Pin = 2;
                app.analogEMG1Pin = 1;
                app.CH2DropDown.Value = app.CH2DropDown.Items{1,1};

            end
        end

        % Button pushed function: ExecuteButton
        function realPIDController_Button_pushed(app, event)
            app.StatusLamp.Color = [255,55,180]./255;
            app.Label.Text = 'PID mode';
            initialValue = 180-(app.ServoAngleLimitsSlider.Value(1)');
            finalValue = 180-(app.ServoAngleLimitsSlider.Value(2)');
            repetitions = uint8(app.StepRepetitionsSpinner.Value);
            period = app.period;
            T = (app.repetitions * (2*period))+2;% Total time duration of motion in seconds, add half period at start and end of the input signal
            dt = 0.02; % Time step
            time_vector = 0:dt:T; % Time vector

            progressbar_servo = uiprogressdlg(app.CLASSROOMEXO_Figure, 'Title','Please Wait','Message','Sending Parameters...', 'Icon','info');
            progressbar_servo.Value = 0.3;
                

            app.ServoTrajectory.XLim = [0 max(time_vector)];
            app.ServoTrajectory.XTick = 0:(dt*50):max(time_vector);
            app.ServoTrajectory.XTickLabel = 0:(dt*50):max(time_vector);
           
            switch (app.signalType)
                
                case 1
                    app.CommFunctions.setInputResponseParams(app.signalType, single(initialValue), single(finalValue), uint32((period*1000)), repetitions, uint32((period*1000)/2));
                    progressbar_servo.Value = 0.6;
                case 2
                    app.CommFunctions.setInputResponseParams(app.signalType, single(initialValue), single(finalValue), uint32((period*1000)), repetitions, uint32((period*1000)/4));
                    progressbar_servo.Value = 0.6;
                case 3
                    app.CommFunctions.setCosineResponseParams(app.signalType, single(initialValue), single(finalValue), uint32((period*1000)), repetitions, uint32((period*1000)/4));
                    progressbar_servo.Value = 0.6;
            end
            progressbar_servo.Value = 0.9;
            app.CommFunctions.setPIDControlParams(single(app.ControllerParams.Ki), single(app.ControllerParams.Kp), single(app.ControllerParams.Kd));
            progressbar_servo.Value = 1;
            
            close(progressbar_servo);
            
            app.CommFunctions.startPIDController();

            h = animatedline(app.ServoTrajectory, nan, nan, 'LineStyle', '-');
            h2 = animatedline(app.ServoTrajectory, nan, nan, 'LineStyle', '--', 'Color', 'cyan');
            i = 1;
            
            try
                while true
                    % Read a line of data
                    
                    %% get the servo Analog degree feedback from the 4th wire in the servo
                    
                    [flag, temp] = app.CommFunctions.getServoAngleStreamFloat();
                    temp_plot = ((temp));
                    pi_stream_flag = flag;

                    % Check for stop bit
                    if ~pi_stream_flag
                        disp('Received stop bit. Ending data acquisition.');
                        break;
                    end
                    
                    % Only plot if we haven't exceeded the time vector length
                    if i <= length(time_vector)
                        hold(app.ServoTrajectory, 'on');
                        addpoints(h, time_vector(i), app.PLOT_degree(i));
                        addpoints(h2, time_vector(i), temp_plot);
                        hold(app.ServoTrajectory, 'off');
                        i = i + 1;
                    else
                        disp('Reached end of time vector. Discarding additional samples.');
                        break;  % Optional: stop acquisition when buffer is full
                    end

                end
                legend(app.ServoTrajectory, [h h2], {'Servo Trajectory', 'Servo Feedback'},'Location','northeast')
                app.Servo_Plot_Reset.Enable = "on";
                CheckBattery(app,2);
                
            catch ME
                disp(i);
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure,report,"Error","Icon","error");
            end


        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            app.RefreshButton_3.Enable = "off";
            clc
            if (~app.ControllerParams.Compare_changes_flag) 
                app.ServoPlotManager.clearStepResponse();
                % cla(app.ServoTrajectory ,'reset'); 
                % cla(app.StepDisturbance ,'reset');
                % cla(app.StepReference ,'reset');
                % % UIXes_2
                % app.ServoTrajectory.XLim = [0 200];
                % app.ServoTrajectory.YLim = [0 140];
                % title(app.ServoTrajectory, 'Simulated Servo Angle');
                % xlabel(app.ServoTrajectory, 'Timesteps [i]');
                % ylabel(app.ServoTrajectory, 'Amplitude [°]');
                % app.ServoTrajectory.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                % app.ServoTrajectory.YTick = [0 20 40 60 80 100 120 140];
                % app.ServoTrajectory.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                % app.ServoTrajectory.YGrid = 'on';
                % 
                % % UIAxes
                % app.StepDisturbance.XLim = [0 200];
                % app.StepDisturbance.YLim = [0 140];
                % title(app.StepDisturbance, 'Step Plot: Input disturbance rejection');
                % xlabel(app.StepDisturbance, 'Timesteps [i]');
                % ylabel(app.StepDisturbance, 'Amplitude');
                % app.StepDisturbance.XLim = [0 200];
                % app.StepDisturbance.YLim = [0 140];
                % app.StepDisturbance.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                % app.StepDisturbance.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                % app.StepDisturbance.YTick = [0 20 40 60 80 100 120 140];
                % app.StepDisturbance.YGrid = 'on';
                % 
                % % UIAxes 3
                % title(app.StepReference, 'Step Plot: Refercence Tracking');
                % xlabel(app.StepReference, 'Timesteps [i]');
                % ylabel(app.StepReference, 'Amplitude');
                % app.StepReference.XLim = [0 200];
                % app.StepReference.YLim = [0 140];
                % app.StepReference.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                % app.StepReference.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                % app.StepReference.YTick = [0 20 40 60 80 100 120 140];
                % app.StepReference.YGrid = 'on';
            
            else
                % UIXes_2
                app.ServoTrajectory.XLim = [0 200];
                app.ServoTrajectory.YLim = [0 140];
                title(app.ServoTrajectory, 'Simulated Servo Angle');
                xlabel(app.ServoTrajectory, 'Timesteps [i]');
                ylabel(app.ServoTrajectory, 'Amplitude [°]');
                app.ServoTrajectory.XTick = [0 20 40 60 80 100 120 140 160 180 200];
                app.ServoTrajectory.YTick = [0 20 40 60 80 100 120 140];
                app.ServoTrajectory.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
                app.ServoTrajectory.YGrid = 'on';
            end
            app.UIStateManager.changeTransferFunctionTab('initial');
            app.RefreshButton_3.Enable = "off";
        end

        % Value changed function: StartRecordingSwitch_IMU_2
        function StartRecordingSwitch_IMU_2ValueChanged(app, event)
            value = app.StartRecordingSwitch_IMU_2.Value;
            try
                if strcmp(value, 'On')
                    app.StatusLamp.Color = "m";
                    app.PlotManager.start({'force'});
                else
                    app.PlotManager.stop();
                end
            catch ME
                report = getReport(ME);
                uialert(app.CLASSROOMEXO_Figure, report, "Error", "Icon", "error");
            end
        end

        % Selection changed function: AppliedFilterButtonGroup_Force
        function AppliedFilterButtonGroup_ForceSelectionChanged(app, event)
            selectedButton = app.AppliedFilterButtonGroup_Force.SelectedObject;
            buttonText = selectedButton.Text;  % Gets "Centered Moving Average" etc.
            
            % Map the display names to the short names
            switch buttonText
                case 'Centered Moving Average'
                    filterType = 'centered';
                case 'Leftsided Moving Average'
                    filterType = 'leftsided';
                case 'EWMA'
                    filterType = 'ewma';
                otherwise
                    error('Unknown filter type');
            end
            app.imuPlotManagerForce.setFilterType('force',filterType, 'Balanced');
        end

        % Selection changed function: AppliedFilterButtonGroup_EMG
        function AppliedFilterButtonGroup_EMGSelectionChanged(app, event)
            selectedButton = app.AppliedFilterButtonGroup_EMG.SelectedObject;
            buttonText = selectedButton.Text;  % Gets "Centered Moving Average" etc.
            
            % Map the display names to the short names
            switch buttonText
                case 'Centered Moving Average'
                    filterType = 'centered';
                case 'Leftsided Moving Average'
                    filterType = 'leftsided';
                case 'EWMA'
                    filterType = 'ewma';
                otherwise
                    error('Unknown filter type');
            end
            app.imuPlotManagerEMG.setFilterType('emg',filterType, 'Balanced');
        end

        % Selection changed function: InputResponseButtonGroup
        function InputResponseButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputResponseButtonGroup.SelectedObject;
            buttonText = selectedButton.Text;  % Gets 

            % Map the display names to the short names
            switch buttonText
                case 'Step'
                    app.signalType = uint8(1);
                case 'Ramp'
                    app.signalType = uint8(2);
                case 'Natural'
                    app.signalType = uint8(3);
                otherwise
                    error('Unknown filter type');
            end
        end

        % Value changed function: ControlModeDropDown
        function ControlModeDropDownValueChanged(app, event)
            selected_control_mode = app.ControlModeDropDown.Value;

            switch selected_control_mode
                case 'Threshold'
                    app.ForceControlMode = 'standard';
                    app.UIStateManager.changeForceControlTab('standard');
                case 'Proportional'
                    app.ForceControlMode = 'proportional';
                    app.UIStateManager.changeForceControlTab('proportional');
                    
                otherwise
                    error('Unknown control mode');
            end
                        
        end

        % Value changed function: proportionalgainSlider
        function proportionalgainSliderValueChanged(app, event)
            value = app.proportionalgainSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CLASSROOMEXO_Figure = uifigure('Visible', 'off');
            app.CLASSROOMEXO_Figure.Name = 'CLASSROOM EXO';
            app.CLASSROOMEXO_Figure.Icon = fullfile(pathToMLAPP, 'photos', 'lastttt.png');
            app.TabGroup = uitabgroup(app.CLASSROOMEXO_Figure);
            app.PIControllerTab = uitab(app.TabGroup);
            app.PIControllerTab.Title = 'PI Controller';
            app.FunctionsResonseTab = uipanel(app.PIControllerTab);
            app.StepDisturbance = uiaxes(app.FunctionsResonseTab);
            title(app.StepDisturbance, 'Step Plot: Input disturbance rejection')
            xlabel(app.StepDisturbance, 'Time [s]')
            ylabel(app.StepDisturbance, 'Ampiltude [°]')
            zlabel(app.StepDisturbance, 'Z')
            app.StepDisturbance.XLim = [0 200];
            app.StepDisturbance.YLim = [0 140];
            app.StepReference = uiaxes(app.FunctionsResonseTab);
            title(app.StepReference, 'Step Plot: Reference Tracking ')
            xlabel(app.StepReference, 'Time [s]')
            ylabel(app.StepReference, 'Amplitude [°]')
            zlabel(app.StepReference, 'Z')
            app.StepReference.XLim = [0 200];
            app.StepReference.YLim = [0 140];
            app.StepReference.XTick = [0 20 40 60 80 100 120 140 160 180 200];
            app.StepReference.XTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'; '120'; '140'; '160'; '180'; '200'};
            app.StepReference.YTick = [0 20 40 60 80 100 120 140];
            app.StepResponsePanel = uipanel(app.FunctionsResonseTab);
            app.StepResponsePanel.Title = 'Step Response';
            app.TransientTimeLabel = uilabel(app.StepResponsePanel);
            app.TransientTimeLabel.Text = 'Rise Time          :';
            app.SettlingTimeLabel = uilabel(app.StepResponsePanel);
            app.SettlingTimeLabel.Text = 'Settling Time    :';
            app.OverShootLabel = uilabel(app.StepResponsePanel);
            app.OverShootLabel.Text = 'OverShoot        :';
            app.PeakLabel = uilabel(app.StepResponsePanel);
            app.PeakLabel.Text = 'Peak                  :';
            app.TimeConstantLabel = uilabel(app.StepResponsePanel);
            app.TimeConstantLabel.Text = 'Time Constant  :';
            app.PolesLabel = uilabel(app.StepResponsePanel);
            app.PolesLabel.Text = 'Poles          :';
            app.ZerosLabel = uilabel(app.StepResponsePanel);
            app.ZerosLabel.Text = 'Zeros          :';
            app.Transfer = uipanel(app.PIControllerTab);
            app.ServoTrajectory = uiaxes(app.Transfer);
            title(app.ServoTrajectory, 'Servo Angle')
            xlabel(app.ServoTrajectory, 'Time [s]')
            ylabel(app.ServoTrajectory, 'Amplitude [°]')
            zlabel(app.ServoTrajectory, 'Z')
            app.ServoTrajectory.XLim = [0 100];
            app.ServoTrajectory.YLim = [0 140];
            app.ServoTrajectory.XTickLabel = {'20'; '40'; '60'; '80'; '100'; '20'; '40'; '60'; '80'; '100'};
            app.TabGroup2 = uitabgroup(app.Transfer);
            app.TransferFunctionTab = uitab(app.TabGroup2);
            app.TransferFunctionTab.Title = 'Transfer Function';
            app.Image7_3 = uiimage(app.TransferFunctionTab);
            app.Image7_3.Enable = 'off';
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_42.png');
            app.Image4_4 = uiimage(app.TransferFunctionTab);
            app.Image4_4.Enable = 'off';
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.Image4_3 = uiimage(app.TransferFunctionTab);
            app.Image4_3.Enable = 'off';
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.Image4_2 = uiimage(app.TransferFunctionTab);
            app.Image4_2.Enable = 'off';
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.Image4 = uiimage(app.TransferFunctionTab);
            app.Image4.Enable = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_75px.png');
            app.ControlDropDown = uidropdown(app.TransferFunctionTab);
            app.ControlDropDown.Items = {'PI control parameters', 'PI design parameters', 'PID design parameters', 'PID control parameters', 'P - control', 'P - , I - control ', 'P - , I - , D - control'};
            app.ControlDropDown.ItemsData = {'1', '2', '4', '3', '5', '6', '7'};
            app.ControlDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlDropDownValueChanged, true);
            app.ControlDropDown.Enable = 'off';
            app.ControlDropDown.Placeholder = 'Control Options';
            app.ControlDropDown.Value = '1';
            app.ServoAngleLimitsSliderLabel = uilabel(app.TransferFunctionTab);
            app.ServoAngleLimitsSliderLabel.Text = 'Servo Angle Limits [°]';
            app.PLOTButton = uibutton(app.TransferFunctionTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.RefreshButton = uibutton(app.TransferFunctionTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.RefreshButton.Text = 'PARAMETERS';
            app.ServoAngleLimitsSlider = uislider(app.TransferFunctionTab, 'range');
            app.ServoAngleLimitsSlider.Limits = [0 110];
            app.ServoAngleLimitsSlider.MajorTicks = [0 25 50 75 100 120];
            app.ServoAngleLimitsSlider.ValueChangedFcn = createCallbackFcn(app, @ServoAngleLimitsSliderValueChanged, true);
            app.ServoAngleLimitsSlider.Enable = 'off';
            app.ServoAngleLimitsSlider.Value = [10 62];
            app.TrackChangesCheckBox = uicheckbox(app.TransferFunctionTab);
            app.TrackChangesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackChangesCheckBoxValueChanged, true);
            app.TrackChangesCheckBox.Text = 'Track Changes';
            app.TrackChangesCheckBox.Value = true;
            app.OptimizePIButton = uibutton(app.TransferFunctionTab, 'push');
            app.OptimizePIButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePIButtonPushed, true);
            app.OptimizePIButton.Text = 'Optimize PI';
            app.TuningPIDPanel_Design_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIDPanel_Design_Params.Enable = 'off';
            app.TuningPIDPanel_Design_Params.Title = 'PID Design Parameters';
            app.TuningPIDPanel_Design_Params.Visible = 'off';
            app.KpLabel_4 = uilabel(app.TuningPIDPanel_Design_Params);
            app.KpLabel_4.Interpreter = 'latex';
            app.KpLabel_4.Text = '\phi$_{m}$';
            app.PhiMEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.PhiMEditFieldPIDDesign.Value = 5;
            app.cLabel_8 = uilabel(app.TuningPIDPanel_Design_Params);
            app.cLabel_8.Interpreter = 'latex';
            app.cLabel_8.Text = '\beta';
            app.BetaEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.BetaEditFieldPIDDesign.Value = 1;
            app.cEditFieldLabel = uilabel(app.TuningPIDPanel_Design_Params);
            app.cEditFieldLabel.Interpreter = 'latex';
            app.cEditFieldLabel.Text = 'ω$_{c}$';
            app.OmegaCEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.OmegaCEditFieldPIDDesign.Value = 5;
            app.ZetaEditFieldPIDDesign = uieditfield(app.TuningPIDPanel_Design_Params, 'numeric');
            app.ZetaEditFieldPIDDesign.Editable = 'off';
            app.ZetaEditFieldPIDDesign.Visible = 'off';
            app.ZetaEditFieldPIDDesign.Tooltip = {'\zeta with constant value of 1'};
            app.ZetaEditFieldPIDDesign.Value = 1;
            app.zetaEditField_3Label_4 = uilabel(app.TuningPIDPanel_Design_Params);
            app.zetaEditField_3Label_4.Visible = 'off';
            app.zetaEditField_3Label_4.Interpreter = 'latex';
            app.zetaEditField_3Label_4.Text = '\zeta';
            app.TuningPIPanel_Design_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIPanel_Design_Params.Enable = 'off';
            app.TuningPIPanel_Design_Params.Title = 'PI Design Parameters';
            app.TuningPIPanel_Design_Params.Visible = 'off';
            app.cLabel_9 = uilabel(app.TuningPIPanel_Design_Params);
            app.cLabel_9.Interpreter = 'latex';
            app.cLabel_9.Text = 'ω$_{c}$';
            app.OmegaCEditFieldPIDesign = uieditfield(app.TuningPIPanel_Design_Params, 'numeric');
            app.OmegaCEditFieldPIDesign.Value = 1.5;
            app.cLabel_10 = uilabel(app.TuningPIPanel_Design_Params);
            app.cLabel_10.Interpreter = 'latex';
            app.cLabel_10.Text = '\phi$_{m}$';
            app.PhiMEditFieldPIDesign = uieditfield(app.TuningPIPanel_Design_Params, 'numeric');
            app.PhiMEditFieldPIDesign.Value = 45;
            app.TuningPIDPanel_Control_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIDPanel_Control_Params.Enable = 'off';
            app.TuningPIDPanel_Control_Params.Title = 'PID Control Parameters';
            app.TuningPIDPanel_Control_Params.Visible = 'off';
            app.KpLabel_5 = uilabel(app.TuningPIDPanel_Control_Params);
            app.KpLabel_5.Interpreter = 'latex';
            app.KpLabel_5.Text = '\tau';
            app.TauEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.TauEditFieldPIDControl.Value = 5;
            app.K_iLabel = uilabel(app.TuningPIDPanel_Control_Params);
            app.K_iLabel.Interpreter = 'latex';
            app.K_iLabel.Text = 'K$_{i}$';
            app.KiEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.KiEditFieldPIDControl.Value = 1;
            app.cLabel_11 = uilabel(app.TuningPIDPanel_Control_Params);
            app.cLabel_11.Interpreter = 'latex';
            app.cLabel_11.Text = '\beta';
            app.BetaEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.BetaEditFieldPIDControl.Value = 1;
            app.ZetaEditFieldPIDControl = uieditfield(app.TuningPIDPanel_Control_Params, 'numeric');
            app.ZetaEditFieldPIDControl.Value = 1;
            app.zetaEditField_3Label_5 = uilabel(app.TuningPIDPanel_Control_Params);
            app.zetaEditField_3Label_5.Interpreter = 'latex';
            app.zetaEditField_3Label_5.Text = '\zeta';
            app.InputResponseButtonGroup = uibuttongroup(app.TransferFunctionTab);
            app.InputResponseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputResponseButtonGroupSelectionChanged, true);
            app.InputResponseButtonGroup.Enable = 'off';
            app.InputResponseButtonGroup.Title = 'Input Response';
            app.StepButton = uiradiobutton(app.InputResponseButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.RampButton = uiradiobutton(app.InputResponseButtonGroup);
            app.RampButton.Text = 'Ramp';
            app.NaturalButton = uiradiobutton(app.InputResponseButtonGroup);
            app.NaturalButton.Enable = 'off';
            app.NaturalButton.Visible = 'off';
            app.NaturalButton.Text = 'Natural';
            app.Image5 = uiimage(app.TransferFunctionTab);
            app.Image5.Enable = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_490px.png');
            app.Image7 = uiimage(app.TransferFunctionTab);
            app.Image7.Enable = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_84px.png');
            app.Image7_2 = uiimage(app.TransferFunctionTab);
            app.Image7_2.Enable = 'off';
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_84px.png');
            app.Image6_4 = uiimage(app.TransferFunctionTab);
            app.Image6_4.Enable = 'off';
            app.Image6_4.ImageSource = fullfile(pathToMLAPP, 'photos', 'line_25px.png');
            app.Image9 = uiimage(app.TransferFunctionTab);
            app.Image9.Enable = 'off';
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small.png');
            app.Gs_PlantPanel = uipanel(app.TransferFunctionTab);
            app.Gs_PlantPanel.Enable = 'off';
            app.Gs_PlantPanel.Title = 'Plant ';
            app.denEditField = uieditfield(app.Gs_PlantPanel, 'text');
            app.denEditField.Placeholder = '(s-0.05)^2(s+1 )';
            app.denEditField.Value = 's^2 + 57.78 s + 770.3';
            app.numEditField = uieditfield(app.Gs_PlantPanel, 'text');
            app.numEditField.Tooltip = {'Set input as factorial : \n or plynomial:'};
            app.numEditField.Placeholder = '(s + 2)';
            app.numEditField.Value = '818.1';
            app.GsLabel = uilabel(app.Gs_PlantPanel);
            app.GsLabel.Text = 'G(s) =';
            app.DivisionLine = uilabel(app.Gs_PlantPanel);
            app.DivisionLine.Text = '_________________';
            app.omega_G150Label = uilabel(app.Gs_PlantPanel);
            app.omega_G150Label.Interpreter = 'tex';
            app.omega_G150Label.Text = '\omega_{G150} = ';
            app.TuningPIPanel_Control_Params = uipanel(app.TransferFunctionTab);
            app.TuningPIPanel_Control_Params.Enable = 'off';
            app.TuningPIPanel_Control_Params.Title = 'PI Control Parameters';
            app.K_iEditField_2Label = uilabel(app.TuningPIPanel_Control_Params);
            app.K_iEditField_2Label.Interpreter = 'latex';
            app.K_iEditField_2Label.Text = 'K$_{i}$';
            app.KiEditFieldPIControl = uieditfield(app.TuningPIPanel_Control_Params, 'numeric');
            app.T_iEditFieldLabel = uilabel(app.TuningPIPanel_Control_Params);
            app.T_iEditFieldLabel.Interpreter = 'latex';
            app.T_iEditFieldLabel.Text = 'T$_{i}$';
            app.TiEditFieldPIControl = uieditfield(app.TuningPIPanel_Control_Params, 'numeric');
            app.LowRefDegreeEditField = uieditfield(app.TransferFunctionTab, 'numeric');
            app.LowRefDegreeEditField.Limits = [0 120];
            app.LowRefDegreeEditField.RoundFractionalValues = 'on';
            app.LowRefDegreeEditField.ValueDisplayFormat = '%11g';
            app.LowRefDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @LowRefDegreeEditFieldValueChanged, true);
            app.LowRefDegreeEditField.Enable = 'off';
            app.LowRefDegreeEditField.Value = 10;
            app.HighRefDegreeEditField = uieditfield(app.TransferFunctionTab, 'numeric');
            app.HighRefDegreeEditField.Limits = [0 120];
            app.HighRefDegreeEditField.RoundFractionalValues = 'on';
            app.HighRefDegreeEditField.ValueDisplayFormat = '%11g';
            app.HighRefDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @HighRefDegreeEditFieldValueChanged, true);
            app.HighRefDegreeEditField.Enable = 'off';
            app.HighRefDegreeEditField.Value = 62;
            app.PIControllerPanel = uipanel(app.TransferFunctionTab);
            app.PIControllerPanel.Enable = 'off';
            app.PIControllerPanel.Title = 'PI Controller';
            app.PIControllerPanel.Visible = 'off';
            app.K_iEditField_2Label_3 = uilabel(app.PIControllerPanel);
            app.K_iEditField_2Label_3.Interpreter = 'latex';
            app.K_iEditField_2Label_3.Text = 'K$_{p}$';
            app.KpEditFieldPIController = uieditfield(app.PIControllerPanel, 'numeric');
            app.K_iLabel_2 = uilabel(app.PIControllerPanel);
            app.K_iLabel_2.Interpreter = 'latex';
            app.K_iLabel_2.Text = 'K$_{i}$';
            app.KiEditFieldPIController = uieditfield(app.PIControllerPanel, 'numeric');
            app.PControllerPanel = uipanel(app.TransferFunctionTab);
            app.PControllerPanel.Enable = 'off';
            app.PControllerPanel.Title = 'P Controller';
            app.PControllerPanel.Visible = 'off';
            app.K_iEditField_2Label_2 = uilabel(app.PControllerPanel);
            app.K_iEditField_2Label_2.Interpreter = 'latex';
            app.K_iEditField_2Label_2.Text = 'K$_{p}$';
            app.KpEditFieldPController = uieditfield(app.PControllerPanel, 'numeric');
            app.PIDControllerPanel = uipanel(app.TransferFunctionTab);
            app.PIDControllerPanel.Enable = 'off';
            app.PIDControllerPanel.Title = 'PID Controller';
            app.PIDControllerPanel.Visible = 'off';
            app.K_iEditField_2Label_4 = uilabel(app.PIDControllerPanel);
            app.K_iEditField_2Label_4.Interpreter = 'latex';
            app.K_iEditField_2Label_4.Text = 'K$_{p}$';
            app.KpEditFieldPIDController = uieditfield(app.PIDControllerPanel, 'numeric');
            app.K_iLabel_3 = uilabel(app.PIDControllerPanel);
            app.K_iLabel_3.Interpreter = 'latex';
            app.K_iLabel_3.Text = 'K$_{i}$';
            app.KiEditFieldPIDController = uieditfield(app.PIDControllerPanel, 'numeric');
            app.K_iLabel_4 = uilabel(app.PIDControllerPanel);
            app.K_iLabel_4.Interpreter = 'latex';
            app.K_iLabel_4.Text = 'K$_{d}$';
            app.KdEditFieldPIDController = uieditfield(app.PIDControllerPanel, 'numeric');
            app.Image9_4 = uiimage(app.TransferFunctionTab);
            app.Image9_4.Enable = 'off';
            app.Image9_4.ImageSource = fullfile(pathToMLAPP, 'photos', 'circle.png');
            app.Image9_5 = uiimage(app.TransferFunctionTab);
            app.Image9_5.Enable = 'off';
            app.Image9_5.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small_down.png');
            app.Image9_6 = uiimage(app.TransferFunctionTab);
            app.Image9_6.Enable = 'off';
            app.Image9_6.ImageSource = fullfile(pathToMLAPP, 'photos', 'circle.png');
            app.Image9_7 = uiimage(app.TransferFunctionTab);
            app.Image9_7.Enable = 'off';
            app.Image9_7.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small.png');
            app.Image9_9 = uiimage(app.TransferFunctionTab);
            app.Image9_9.Enable = 'off';
            app.Image9_9.ImageSource = fullfile(pathToMLAPP, 'photos', 'circle.png');
            app.Image9_10 = uiimage(app.TransferFunctionTab);
            app.Image9_10.Enable = 'off';
            app.Image9_10.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small_up.png');
            app.Image9_11 = uiimage(app.TransferFunctionTab);
            app.Image9_11.Enable = 'off';
            app.Image9_11.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small.png');
            app.Image9_8 = uiimage(app.TransferFunctionTab);
            app.Image9_8.Enable = 'off';
            app.Image9_8.ImageSource = fullfile(pathToMLAPP, 'photos', 'arrow_small_down.png');
            app.rLabel = uilabel(app.TransferFunctionTab);
            app.rLabel.Enable = 'off';
            app.rLabel.Interpreter = 'tex';
            app.rLabel.Text = 'r';
            app.Label_2 = uilabel(app.TransferFunctionTab);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '_';
            app.Label_3 = uilabel(app.TransferFunctionTab);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '+';
            app.Label_4 = uilabel(app.TransferFunctionTab);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '+';
            app.Label_5 = uilabel(app.TransferFunctionTab);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '+';
            app.yLabel = uilabel(app.TransferFunctionTab);
            app.yLabel.Enable = 'off';
            app.yLabel.Interpreter = 'tex';
            app.yLabel.Text = 'y';
            app.y_mLabel = uilabel(app.TransferFunctionTab);
            app.y_mLabel.Enable = 'off';
            app.y_mLabel.Interpreter = 'tex';
            app.y_mLabel.Text = 'y_{m}';
            app.Label_6 = uilabel(app.TransferFunctionTab);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '+';
            app.vLabel = uilabel(app.TransferFunctionTab);
            app.vLabel.Enable = 'off';
            app.vLabel.Interpreter = 'tex';
            app.vLabel.Text = 'v';
            app.uLabel = uilabel(app.TransferFunctionTab);
            app.uLabel.Enable = 'off';
            app.uLabel.Interpreter = 'tex';
            app.uLabel.Text = 'u';
            app.OptimizePIDButton = uibutton(app.TransferFunctionTab, 'push');
            app.OptimizePIDButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizePIDButtonPushed, true);
            app.OptimizePIDButton.Visible = 'off';
            app.OptimizePIDButton.Text = 'Optimize PID';
            app.ShowInputSignalCheckBox = uicheckbox(app.TransferFunctionTab);
            app.ShowInputSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowInputSignalCheckBoxValueChanged, true);
            app.ShowInputSignalCheckBox.Text = 'Show Input Signal';
            app.ShowInputSignalCheckBox.Value = true;
            app.RefreshButton_3 = uibutton(app.TransferFunctionTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.RefreshButton_3.Text = '';
            app.InputSignalTab = uitab(app.TabGroup2);
            app.InputSignalTab.Title = 'Input Signal';
            app.InputSignalGraph = uiaxes(app.InputSignalTab);
            title(app.InputSignalGraph, 'Input Signal')
            xlabel(app.InputSignalGraph, 'Time [s]')
            ylabel(app.InputSignalGraph, 'Amplitude [°]')
            zlabel(app.InputSignalGraph, 'Z')
            app.InputSignalGraph.XLim = [0 60];
            app.InputSignalGraph.YLim = [0 140];
            app.InputSignalGraph.ZTick = [];
            app.StepSignalParametersLabel = uilabel(app.InputSignalTab);
            app.StepSignalParametersLabel.Text = 'Step Signal Parameters';
            app.RampSignalParametersLabel = uilabel(app.InputSignalTab);
            app.RampSignalParametersLabel.Text = 'Ramp Signal Parameters';
            app.NaturalMovementSignalParametersLabel = uilabel(app.InputSignalTab);
            app.NaturalMovementSignalParametersLabel.Text = 'Natural Movement Signal Parameters';
            app.RepetitionsSpinnerLabel = uilabel(app.InputSignalTab);
            app.RepetitionsSpinnerLabel.Text = 'Repetitions';
            app.StepRepetitionsSpinner = uispinner(app.InputSignalTab);
            app.StepRepetitionsSpinner.Limits = [0 5];
            app.StepRepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepPeriodSpinnerValueChanged, true);
            app.StepRepetitionsSpinner.Value = 1;
            app.PeriodSpinnerLabel = uilabel(app.InputSignalTab);
            app.PeriodSpinnerLabel.Text = 'Period';
            app.StepPeriodSpinner = uispinner(app.InputSignalTab);
            app.StepPeriodSpinner.Step = 0.2;
            app.StepPeriodSpinner.Limits = [0 10];
            app.StepPeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @StepPeriodSpinnerValueChanged, true);
            app.StepPeriodSpinner.Value = 4;
            app.RepetitionsSpinner_2Label = uilabel(app.InputSignalTab);
            app.RepetitionsSpinner_2Label.Text = 'Repetitions';
            app.RampRepetitionsSpinner = uispinner(app.InputSignalTab);
            app.RampRepetitionsSpinner.Limits = [0 5];
            app.RampRepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @RampPeriodSpinnerValueChanged, true);
            app.RampRepetitionsSpinner.Value = 1;
            app.PeriodSpinner_2Label = uilabel(app.InputSignalTab);
            app.PeriodSpinner_2Label.Text = 'Period';
            app.RampPeriodSpinner = uispinner(app.InputSignalTab);
            app.RampPeriodSpinner.Step = 0.2;
            app.RampPeriodSpinner.Limits = [0 2];
            app.RampPeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @RampPeriodSpinnerValueChanged, true);
            app.RampPeriodSpinner.Value = 2;
            app.RepetitionsSpinner_2Label_2 = uilabel(app.InputSignalTab);
            app.RepetitionsSpinner_2Label_2.Text = 'Repetitions';
            app.NaturalRepetitionsSpinner = uispinner(app.InputSignalTab);
            app.NaturalRepetitionsSpinner.Limits = [0 5];
            app.NaturalRepetitionsSpinner.ValueChangedFcn = createCallbackFcn(app, @NaturalPeriodSpinnerValueChanged, true);
            app.NaturalRepetitionsSpinner.Value = 1;
            app.PeriodSpinner_2Label_2 = uilabel(app.InputSignalTab);
            app.PeriodSpinner_2Label_2.Text = 'Period';
            app.NaturalPeriodSpinner = uispinner(app.InputSignalTab);
            app.NaturalPeriodSpinner.Step = 0.2;
            app.NaturalPeriodSpinner.Limits = [0 5];
            app.NaturalPeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @NaturalPeriodSpinnerValueChanged, true);
            app.NaturalPeriodSpinner.Value = 3;
            app.ServoControlPanel = uipanel(app.Transfer);
            app.ServoControlPanel.Title = 'Servo Control';
            app.ServoPowerSwitchLabel = uilabel(app.ServoControlPanel);
            app.ServoPowerSwitchLabel.Enable = 'off';
            app.ServoPowerSwitchLabel.Text = 'Servo Power';
            app.Servo_Plot_Reset = uibutton(app.ServoControlPanel, 'push');
            app.Servo_Plot_Reset.ButtonPushedFcn = createCallbackFcn(app, @Servo_Plot_ResetPushed, true);
            app.Servo_Plot_Reset.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.Servo_Plot_Reset.Enable = 'off';
            app.Servo_Plot_Reset.Text = '';
            app.ExecuteButton = uibutton(app.ServoControlPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @realPIDController_Button_pushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute ';
            app.ServoPowerSwitch = uiswitch(app.ServoControlPanel, 'slider');
            app.ServoPowerSwitch.ValueChangedFcn = createCallbackFcn(app, @ServoPowerSwitchValueChanged, true);
            app.ServoPowerSwitch.Enable = 'off';
            app.SpeedKnobLabel = uilabel(app.ServoControlPanel);
            app.SpeedKnobLabel.Enable = 'off';
            app.SpeedKnobLabel.Text = 'Speed';
            app.ServoSpeedKnob = uiknob(app.ServoControlPanel, 'discrete');
            app.ServoSpeedKnob.Items = {'Low', 'default', 'Medium', 'High'};
            app.ServoSpeedKnob.ItemsData = {'1', '2', '3', '4'};
            app.ServoSpeedKnob.Enable = 'off';
            app.ServoSpeedKnob.Visible = 'off';
            app.ServoSpeedKnob.Value = '1';
            app.EMGControlTab = uitab(app.TabGroup);
            app.EMGControlTab.Title = 'EMG Control';
            app.TabGroup3 = uitabgroup(app.EMGControlTab);
            app.ThresholdControlTab = uitab(app.TabGroup3);
            app.ThresholdControlTab.Title = 'Threshold Control';
            app.ElectromyographyControlGraphs_2 = uipanel(app.ThresholdControlTab);
            app.ElectromyographyControlGraphs_2.Title = 'Electromyography Control';
            app.EMG_OneCH = uiaxes(app.ElectromyographyControlGraphs_2);
            xlabel(app.EMG_OneCH, 'Time [s]')
            ylabel(app.EMG_OneCH, 'ENV Amplitude [µV]')
            zlabel(app.EMG_OneCH, 'Z')
            app.EMG_OneCH.XLim = [0 400];
            app.EMG_OneCH.YLim = [0 0.35];
            app.EMG_OneCH.XTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.EMG_OneCH.YTick = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35];
            app.EMG_OneCH.YTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'; '300'; '350'};
            app.EMG_OneCH.ZTick = [];
            app.IMUPlot_EMG = uiaxes(app.ElectromyographyControlGraphs_2);
            title(app.IMUPlot_EMG, '3D Position Estimation')
            app.IMUPlot_EMG.XLim = [0 100];
            app.IMUPlot_EMG.YLim = [-1500 1500];
            app.IMUPlot_EMG.XTick = [];
            app.IMUPlot_EMG.YTick = [];
            app.IMUPlot_EMG.YTickLabel = '';
            app.EMGControlParameters_2 = uipanel(app.ThresholdControlTab);
            app.EMGControlParameters_2.Enable = 'off';
            app.EMGControlParameters_2.Title = 'Parameters';
            app.CH1DropDown = uidropdown(app.EMGControlParameters_2);
            app.CH1DropDown.Items = {'EMG0', 'EMG1'};
            app.CH1DropDown.ValueChangedFcn = createCallbackFcn(app, @CH1DropDownValueChanged, true);
            app.CH1DropDown.Placeholder = 'choose Channel';
            app.CH1DropDown.Value = 'EMG0';
            app.StartSwitchLabel_4 = uilabel(app.EMGControlParameters_2);
            app.StartSwitchLabel_4.Text = 'Start';
            app.StartRecordingSwitch_DualThresh_EMG = uiswitch(app.EMGControlParameters_2, 'rocker');
            app.StartRecordingSwitch_DualThresh_EMG.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_DualThresh_EMGValueChanged, true);
            app.ThresholdVLabel = uilabel(app.EMGControlParameters_2);
            app.ThresholdVLabel.Text = 'Threshold [µV]';
            app.EMGChannelLabel_2 = uilabel(app.EMGControlParameters_2);
            app.EMGChannelLabel_2.Text = 'EMG Channel';
            app.AppliedFilterButtonGroup_EMG = uibuttongroup(app.EMGControlParameters_2);
            app.AppliedFilterButtonGroup_EMG.SelectionChangedFcn = createCallbackFcn(app, @AppliedFilterButtonGroup_EMGSelectionChanged, true);
            app.AppliedFilterButtonGroup_EMG.Title = 'Applied Filter';
            app.EMG_LeftSidedMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_LeftSidedMovingAverageButton.Text = 'Leftsided Moving Average';
            app.EMG_LeftSidedMovingAverageButton.Value = true;
            app.EMG_CenteredMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_CenteredMovingAverageButton.Text = 'Centered Moving Average';
            app.EMG_EWMVA = uiradiobutton(app.AppliedFilterButtonGroup_EMG);
            app.EMG_EWMVA.Text = 'EWMA';
            app.ModeSwitch_2Label = uilabel(app.EMGControlParameters_2);
            app.ModeSwitch_2Label.Enable = 'off';
            app.ModeSwitch_2Label.Visible = 'off';
            app.ModeSwitch_2Label.Text = 'Mode';
            app.ModeSwitch_2 = uiswitch(app.EMGControlParameters_2, 'rocker');
            app.ModeSwitch_2.Items = {'Min/Max', 'Increment'};
            app.ModeSwitch_2.Enable = 'off';
            app.ModeSwitch_2.Visible = 'off';
            app.ModeSwitch_2.Value = 'Min/Max';
            app.EMGShowThresholdCheckBox = uicheckbox(app.EMGControlParameters_2);
            app.EMGShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @EMGShowThresholdCheckBoxValueChanged, true);
            app.EMGShowThresholdCheckBox.Text = 'Show Threshold';
            app.UpperLabel = uilabel(app.EMGControlParameters_2);
            app.UpperLabel.Text = 'Upper';
            app.EMGUpperThresholdSlider = uislider(app.EMGControlParameters_2);
            app.EMGUpperThresholdSlider.Limits = [0 330];
            app.EMGUpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EMGUpperThresholdSliderValueChanged, true);
            app.EMGUpperThresholdSlider.Value = 200;
            app.LowerLabel = uilabel(app.EMGControlParameters_2);
            app.LowerLabel.Text = 'Lower';
            app.EMGLowerThresholdSlider = uislider(app.EMGControlParameters_2);
            app.EMGLowerThresholdSlider.Limits = [0 330];
            app.EMGLowerThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EMGLowerThresholdSliderValueChanged, true);
            app.EMGLowerThresholdSlider.Value = 100;
            app.ApplyThresholdsButton_EMG = uibutton(app.EMGControlParameters_2, 'state');
            app.ApplyThresholdsButton_EMG.ValueChangedFcn = createCallbackFcn(app, @ApplyThresholdsButton_EMGValueChanged, true);
            app.ApplyThresholdsButton_EMG.Text = 'Apply Thresholds';
            app.ServoMotorSpeedLabel = uilabel(app.EMGControlParameters_2);
            app.ServoMotorSpeedLabel.Text = 'Servo Motor Speed';
            app.SpeedSetting_EMG = uiknob(app.EMGControlParameters_2, 'discrete');
            app.SpeedSetting_EMG.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_EMG.ItemsData = [1 2 3 4];
            app.SpeedSetting_EMG.Value = 1;
            app.EMGUpperThreshold = uieditfield(app.EMGControlParameters_2, 'numeric');
            app.EMGUpperThreshold.Limits = [0 330];
            app.EMGUpperThreshold.ValueChangedFcn = createCallbackFcn(app, @EMGUpperThresholdValueChanged, true);
            app.EMGUpperThreshold.Value = 200;
            app.EMGLowerThreshold = uieditfield(app.EMGControlParameters_2, 'numeric');
            app.EMGLowerThreshold.Limits = [0 330];
            app.EMGLowerThreshold.ValueChangedFcn = createCallbackFcn(app, @EMGLowerThresholdValueChanged, true);
            app.EMGLowerThreshold.Value = 100;
            app.CH2DropDown = uidropdown(app.EMGControlParameters_2);
            app.CH2DropDown.Items = {'EMG0', 'EMG1'};
            app.CH2DropDown.ValueChangedFcn = createCallbackFcn(app, @CH2DropDownValueChanged, true);
            app.CH2DropDown.Visible = 'off';
            app.CH2DropDown.Placeholder = 'choose Channel';
            app.CH2DropDown.Value = 'EMG1';
            app.EMGModeSwitch = uiswitch(app.EMGControlParameters_2, 'slider');
            app.EMGModeSwitch.Items = {'Mono EMG', 'Bi EMG'};
            app.EMGModeSwitch.ValueChangedFcn = createCallbackFcn(app, @EMGModeSwitchValueChanged, true);
            app.EMGModeSwitch.Value = 'Mono EMG';
            app.ForceControlTab = uitab(app.TabGroup);
            app.ForceControlTab.Title = 'Force Control';
            app.TabGroup3_2 = uitabgroup(app.ForceControlTab);
            app.ThresholdTab = uitab(app.TabGroup3_2);
            app.ThresholdTab.Title = 'Threshold ';
            app.ForceControlGraphs = uipanel(app.ThresholdTab);
            app.ForceControlGraphs.Title = 'Force Control';
            app.ForceReading = uiaxes(app.ForceControlGraphs);
            title(app.ForceReading, 'Force Sensor')
            xlabel(app.ForceReading, 'Time [s]')
            ylabel(app.ForceReading, 'Force Amplitude [mN]')
            zlabel(app.ForceReading, 'Z')
            app.ForceReading.XLim = [0 100];
            app.ForceReading.YLim = [-5000 5000];
            app.ForceReading.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ForceReading.XTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'; '4.5'; '5'; '5.5'; '6'; '6.5'; '7'; '7.5'; '8'; '8.5'; '9'; '9.5'; '10'};
            app.ForceReading.YTick = [-5000 -4000 -3000 -2000 -1000 0 1000 2000 3000 4000 5000];
            app.ForceReading.YTickLabel = {'-5000'; '-4000'; '-3000'; '-2000'; '-1000'; '0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.IMUPlot_Force = uiaxes(app.ForceControlGraphs);
            title(app.IMUPlot_Force, '3D Position Estimation')
            app.IMUPlot_Force.XLim = [0 100];
            app.IMUPlot_Force.YLim = [-1500 1500];
            app.IMUPlot_Force.XTick = [];
            app.IMUPlot_Force.YTick = [];
            app.IMUPlot_Force.YTickLabel = '';
            app.ForceParameters = uipanel(app.ForceControlGraphs);
            app.ForceParameters.Enable = 'off';
            app.ForceParameters.Title = 'Parameters';
            app.UpperThresholdSliderLabel = uilabel(app.ForceParameters);
            app.UpperThresholdSliderLabel.Text = 'Upper Threshold';
            app.ForceUpperThresholdSlider = uislider(app.ForceParameters);
            app.ForceUpperThresholdSlider.Limits = [0 1000];
            app.ForceUpperThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ForceUpperThresholdSliderValueChanged, true);
            app.ForceUpperThresholdSlider.Value = 500;
            app.LowerThresholdSliderLabel = uilabel(app.ForceParameters);
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.ForceLowerThresholdSlider = uislider(app.ForceParameters);
            app.ForceLowerThresholdSlider.Limits = [-1000 0];
            app.ForceLowerThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ForceLowerThresholdSliderValueChanged, true);
            app.ForceLowerThresholdSlider.Value = -500;
            app.StartSwitchLabel_7 = uilabel(app.ForceParameters);
            app.StartSwitchLabel_7.Text = 'Start';
            app.StartRecordingSwitch_DualThresh_Force = uiswitch(app.ForceParameters, 'rocker');
            app.StartRecordingSwitch_DualThresh_Force.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_DualThresh_ForceValueChanged, true);
            app.AppliedFilterButtonGroup_Force = uibuttongroup(app.ForceParameters);
            app.AppliedFilterButtonGroup_Force.SelectionChangedFcn = createCallbackFcn(app, @AppliedFilterButtonGroup_ForceSelectionChanged, true);
            app.AppliedFilterButtonGroup_Force.Title = 'Applied Filter';
            app.LeftsidedMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.LeftsidedMovingAverageButton.Text = 'Leftsided Moving Average';
            app.LeftsidedMovingAverageButton.Value = true;
            app.CenteredMovingAverageButton = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.CenteredMovingAverageButton.Text = 'Centered Moving Average';
            app.EWMAButton = uiradiobutton(app.AppliedFilterButtonGroup_Force);
            app.EWMAButton.Text = 'EWMA';
            app.ApplyThresholdsButton_Force = uibutton(app.ForceParameters, 'state');
            app.ApplyThresholdsButton_Force.ValueChangedFcn = createCallbackFcn(app, @ApplyThresholdsButton_2ValueChanged, true);
            app.ApplyThresholdsButton_Force.Text = 'Apply Thresholds';
            app.ServoMotorSpeedLabel_4 = uilabel(app.ForceParameters);
            app.ServoMotorSpeedLabel_4.Text = 'Servo Motor Speed';
            app.SpeedSetting_Force = uiknob(app.ForceParameters, 'discrete');
            app.SpeedSetting_Force.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_Force.ItemsData = {'1', '2', '3', '4'};
            app.SpeedSetting_Force.Value = '1';
            app.ForceShowThresholdCheckBox = uicheckbox(app.ForceParameters);
            app.ForceShowThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceShowThresholdCheckBoxValueChanged, true);
            app.ForceShowThresholdCheckBox.Text = 'Show Threshold';
            app.ForceShowThresholdCheckBox.Value = true;
            app.ThresholdmNLabel = uilabel(app.ForceParameters);
            app.ThresholdmNLabel.Text = 'Threshold [mN]';
            app.ControlModeDropDownLabel = uilabel(app.ForceParameters);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlModeDropDown = uidropdown(app.ForceParameters);
            app.ControlModeDropDown.Items = {'Threshold', 'Proportional'};
            app.ControlModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlModeDropDownValueChanged, true);
            app.ControlModeDropDown.Value = 'Threshold';
            app.proportionalgainSliderLabel = uilabel(app.ForceParameters);
            app.proportionalgainSliderLabel.Text = 'proportional gain';
            app.proportionalgainSlider = uislider(app.ForceParameters);
            app.proportionalgainSlider.Limits = [0 1];
            app.proportionalgainSlider.ValueChangedFcn = createCallbackFcn(app, @proportionalgainSliderValueChanged, true);
            app.ArmWeightCompensationTab = uitab(app.TabGroup3_2);
            app.ArmWeightCompensationTab.Title = 'Arm Weight Compensation';
            app.ForceControlGraphs_2 = uipanel(app.ArmWeightCompensationTab);
            app.ForceControlGraphs_2.Title = 'Force Control';
            app.ForceReading_2 = uiaxes(app.ForceControlGraphs_2);
            title(app.ForceReading_2, 'EMG Sensor')
            xlabel(app.ForceReading_2, 'Time [s]')
            ylabel(app.ForceReading_2, 'ENV Amplitude [mV]')
            zlabel(app.ForceReading_2, 'Z')
            app.ForceReading_2.XLim = [0 100];
            app.ForceReading_2.YLim = [-1500 1500];
            app.ForceReading_2.YTick = [-5000 -4000 -3000 -2000 -1000 0 1000 2000 3000 4000 5000];
            app.ForceReading_2.YTickLabel = {'-5000'; '-4000'; '-3000'; '-2000'; '-1000'; '0'; '1000'; '2000'; '3000'; '4000'; '5000'};
            app.IMUPlot_Force_2 = uiaxes(app.ForceControlGraphs_2);
            title(app.IMUPlot_Force_2, '3D Position Estimation')
            app.IMUPlot_Force_2.XLim = [0 100];
            app.IMUPlot_Force_2.YLim = [-1500 1500];
            app.IMUPlot_Force_2.XTick = [];
            app.IMUPlot_Force_2.YTick = [];
            app.IMUPlot_Force_2.YTickLabel = '';
            app.ForceParameters_2 = uipanel(app.ForceControlGraphs_2);
            app.ForceParameters_2.Title = 'Parameters';
            app.StartSwitchLabel_8 = uilabel(app.ForceParameters_2);
            app.StartSwitchLabel_8.Text = 'Start';
            app.StartRecordingSwitch_Force_2 = uiswitch(app.ForceParameters_2, 'rocker');
            app.AppliedFilterButtonGroup_Force_2 = uibuttongroup(app.ForceParameters_2);
            app.AppliedFilterButtonGroup_Force_2.Title = 'Applied Filter';
            app.LeftSidedMovingAverageButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force_2);
            app.LeftSidedMovingAverageButton_2.Text = {'Left Sided '; 'Moving Average'};
            app.LeftSidedMovingAverageButton_2.Value = true;
            app.CenteredMovingAverageButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force_2);
            app.CenteredMovingAverageButton_2.Text = {'Centered '; 'Moving Average'};
            app.EWMVAButton_2 = uiradiobutton(app.AppliedFilterButtonGroup_Force_2);
            app.EWMVAButton_2.Text = 'EWMVA';
            app.ServoMotorSpeedLabel_5 = uilabel(app.ForceParameters_2);
            app.ServoMotorSpeedLabel_5.Text = 'Servo Motor Speed';
            app.SpeedSetting_Force_2 = uiknob(app.ForceParameters_2, 'discrete');
            app.SpeedSetting_Force_2.Items = {'Low', 'default', 'Medium', 'High'};
            app.SpeedSetting_Force_2.ItemsData = {'1', '2', '3', '4'};
            app.SpeedSetting_Force_2.Value = '1';
            app.UserHeightmSpinnerLabel = uilabel(app.ForceParameters_2);
            app.UserHeightmSpinnerLabel.Text = 'User Height  [m]';
            app.UserHeightmSpinner = uispinner(app.ForceParameters_2);
            app.UserHeightmSpinner.Step = 0.01;
            app.UserHeightmSpinner.Limits = [1 2.5];
            app.UserHeightmSpinner.Value = 1.58;
            app.UserWeightkgSpinnerLabel = uilabel(app.ForceParameters_2);
            app.UserWeightkgSpinnerLabel.Text = 'User Weight [kg]';
            app.UserWeightkgSpinner = uispinner(app.ForceParameters_2);
            app.UserWeightkgSpinner.Limits = [20 200];
            app.UserWeightkgSpinner.Value = 50;
            app.UserSpecificParametersLabel = uilabel(app.ForceParameters_2);
            app.UserSpecificParametersLabel.Text = 'User Specific Parameters';
            app.ServoPowerSwitch_2Label = uilabel(app.ForceParameters_2);
            app.ServoPowerSwitch_2Label.Text = 'Servo Power';
            app.ServoPowerSwitch_2 = uiswitch(app.ForceParameters_2, 'slider');
            app.IMUPosePlotTab = uitab(app.TabGroup);
            app.IMUPosePlotTab.Title = 'IMU Pose';
            app.IMUPosePlot = uipanel(app.IMUPosePlotTab);
            app.IMUPosePlot.Title = 'IMU Plot';
            app.IMUPlot = uiaxes(app.IMUPosePlot);
            title(app.IMUPlot, '3D Position Estimation')
            xlabel(app.IMUPlot, 'Time [s]')
            zlabel(app.IMUPlot, 'Z')
            app.IMUPlot.XLim = [0 100];
            app.IMUPlot.YLim = [-1500 1500];
            app.IMUPlot.XTick = [];
            app.IMUPlot.XTickLabel = '';
            app.IMUPlot.YTick = [];
            app.IMUPlot.ZTick = [];
            app.IMUPlot.Visible = 'off';
            app.UIAxes = uiaxes(app.IMUPosePlot);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IMU_Parameters = uipanel(app.IMUPosePlotTab);
            app.IMU_Parameters.Title = 'Parameters';
            app.StartSwitchLabel_5 = uilabel(app.IMU_Parameters);
            app.StartSwitchLabel_5.Text = 'Start';
            app.StartRecordingSwitch_IMU = uiswitch(app.IMU_Parameters, 'rocker');
            app.StartRecordingSwitch_IMU.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_IMUValueChanged, true);
            app.StartSwitchLabel_9 = uilabel(app.IMU_Parameters);
            app.StartSwitchLabel_9.Text = 'Start';
            app.StartRecordingSwitch_IMU_2 = uiswitch(app.IMU_Parameters, 'rocker');
            app.StartRecordingSwitch_IMU_2.ValueChangedFcn = createCallbackFcn(app, @StartRecordingSwitch_IMU_2ValueChanged, true);
            app.BatteryManager = uipanel(app.CLASSROOMEXO_Figure);
            app.BatteryImage = uiimage(app.BatteryManager);
            app.BatteryImage.ImageSource = fullfile(pathToMLAPP, 'photos', 'fa-battery-4.png');
            app.BatteryPercentage = uilabel(app.BatteryManager);
            app.BatteryPercentage.Text = '';
            app.StatusManager = uipanel(app.CLASSROOMEXO_Figure);
            app.Label = uilabel(app.StatusManager);
            app.Label.Text = '';
            app.StatusLamp = uilamp(app.StatusManager);
            app.ConnectionManager = uipanel(app.CLASSROOMEXO_Figure);
            app.ConnectButton = uibutton(app.ConnectionManager, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.BTStatusImage = uiimage(app.ConnectionManager);
            app.BTDeviceDropDownLabel = uilabel(app.ConnectionManager);
            app.BTDeviceDropDownLabel.Text = 'BT Device';
            app.BTDeviceDropDown = uidropdown(app.ConnectionManager);
            app.BTDeviceDropDown.Items = {};
            app.BTDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @BTDeviceDropDownValueChanged, true);
            app.BTDeviceDropDown.Enable = 'off';
            app.BTDeviceDropDown.Placeholder = 'select device';
            app.BTDeviceDropDown.ClickedFcn = createCallbackFcn(app, @BTDeviceDropDownClicked, true);
            app.BTDeviceDropDown.Value = {};
            app.RefreshButton_Connection_List = uibutton(app.ConnectionManager, 'push');
            app.RefreshButton_Connection_List.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_Connection_ListPushed, true);
            app.RefreshButton_Connection_List.Icon = fullfile(pathToMLAPP, 'photos', 'reload.png');
            app.RefreshButton_Connection_List.Enable = 'off';
            app.RefreshButton_Connection_List.Text = '';
            app.EmergencyManager = uipanel(app.CLASSROOMEXO_Figure);
            app.EmergnecySTOPButton = uibutton(app.EmergencyManager, 'state');
            app.EmergnecySTOPButton.ValueChangedFcn = createCallbackFcn(app, @EmergnecySTOPButtonValueChanged, true);
            app.EmergnecySTOPButton.Icon = fullfile(pathToMLAPP, 'photos', 'stop-button.png');
            app.EmergnecySTOPButton.Text = '';
            app.Image = uiimage(app.CLASSROOMEXO_Figure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'photos', 'lastttt.png');
            app.Image_2 = uiimage(app.CLASSROOMEXO_Figure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'photos', 'chalmers-university-of-technology-.png');
            % Show the figure after all components are created
            app.CLASSROOMEXO_Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CLASSROOMEXO_Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CLASSROOMEXO_Figure)
        end
    end
end